classdef CorALate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TypeLabel                       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Button_DIC3DButton              matlab.ui.control.Button
        Button_PT                       matlab.ui.control.Button
        Button_DVCButton                matlab.ui.control.Button
        Button_DICButton                matlab.ui.control.Button
        Panel_TPT                       matlab.ui.container.Panel
        Button_ST3D                     matlab.ui.control.Button
        Button_ST2D                     matlab.ui.control.Button
        Panel_DIC                       matlab.ui.container.Panel
        Button_FIDIC                    matlab.ui.control.Button
        Button_qDIC                     matlab.ui.control.Button
        Button_ALDIC                    matlab.ui.control.Button
        LoadExperimentDataTab           matlab.ui.container.Tab
        TabGroup_Load3D                 matlab.ui.container.TabGroup
        LoadImagesTab_3D                matlab.ui.container.Tab
        InputmtopixelratioZ_3D          matlab.ui.control.NumericEditField
        InputmtopixelratioinZstepsizeLabel  matlab.ui.control.Label
        UITable_ImageList_3D            matlab.ui.control.Table
        Button_LoadIMStack_3D           matlab.ui.control.Button
        InputmtopixelratioXY_3D         matlab.ui.control.NumericEditField
        InputmtopixelratioinXYplaneLabel  matlab.ui.control.Label
        ImageListLabel_3D               matlab.ui.control.Label
        Button_LoadND2                  matlab.ui.control.Button
        ReferenceLabel_3D               matlab.ui.control.Label
        DeformedLabel_3D                matlab.ui.control.Label
        UIAxes_DefVol_3D                matlab.ui.control.UIAxes
        UIAxes_RefVol_3D                matlab.ui.control.UIAxes
        LoadROITab_3D                   matlab.ui.container.Tab
        ButtonGroup_ROI_3D              matlab.ui.container.ButtonGroup
        Image_XZproj_2                  matlab.ui.control.Image
        VolumeButton                    matlab.ui.control.ToggleButton
        Image_XZproj                    matlab.ui.control.Image
        Image_YZproj                    matlab.ui.control.Image
        Image_XYproj                    matlab.ui.control.Image
        XZprojectionButton              matlab.ui.control.ToggleButton
        YZProjectionButton              matlab.ui.control.ToggleButton
        XYprojectionButton              matlab.ui.control.ToggleButton
        ApplyROIPanel_3                 matlab.ui.container.Panel
        Button_ClearAll_3D              matlab.ui.control.Button
        Button_Applytoall_3D            matlab.ui.control.Button
        Button_Applytoselected_3D       matlab.ui.control.Button
        Button_ClearROI_3D              matlab.ui.control.Button
        ModifyROIPanel_3D               matlab.ui.container.Panel
        Button_clearMask_3D             matlab.ui.control.Button
        Button_makeMask_3D              matlab.ui.control.Button
        Button_elip_neg_3D              matlab.ui.control.Button
        Button_poly_neg_3D              matlab.ui.control.Button
        Button_elip_pos_3D              matlab.ui.control.Button
        Button_poly_pos_3D              matlab.ui.control.Button
        Button_cir_neg_3D               matlab.ui.control.Button
        Button_rect_neg_3D              matlab.ui.control.Button
        SubtractmaskLabel_3D            matlab.ui.control.Label
        Button_cir_pos_3D               matlab.ui.control.Button
        AddmaskLabel_3D                 matlab.ui.control.Label
        Button_rect_pos_3D              matlab.ui.control.Button
        Button_LoadROI_single_3D        matlab.ui.control.Button
        UITable_ImageList_ROI_3D        matlab.ui.control.Table
        Button_LoadROI_set_3D           matlab.ui.control.Button
        ImageListLabel_3D_ROI           matlab.ui.control.Label
        UIAxes_ROI_3D                   matlab.ui.control.UIAxes
        TabGroup_Load2D                 matlab.ui.container.TabGroup
        LoadImagesTab                   matlab.ui.container.Tab
        ReverseImageSequenceCheckBox    matlab.ui.control.CheckBox
        CalibrateratiofromimageButton   matlab.ui.control.Button
        InputmtopixelratioEditField     matlab.ui.control.NumericEditField
        InputmtopixelratioEditFieldLabel  matlab.ui.control.Label
        ImageListLabel_2                matlab.ui.control.Label
        UITable_ImageList               matlab.ui.control.Table
        Button_LoadImages               matlab.ui.control.Button
        ReferenceLabel                  matlab.ui.control.Label
        DeformedLabel                   matlab.ui.control.Label
        UIAxes_DefImg                   matlab.ui.control.UIAxes
        UIAxes_RefImg                   matlab.ui.control.UIAxes
        LoadROITab                      matlab.ui.container.Tab
        ApplyROIPanel                   matlab.ui.container.Panel
        Button_ClearAll                 matlab.ui.control.Button
        Button_Applytoall               matlab.ui.control.Button
        Button_Applytoselected          matlab.ui.control.Button
        Button_ClearROI                 matlab.ui.control.Button
        AutoROIPanel                    matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        FilterbasedTab                  matlab.ui.container.Tab
        orderEditField                  matlab.ui.control.NumericEditField
        orderEditFieldLabel             matlab.ui.control.Label
        filterboundsLabel               matlab.ui.control.Label
        highEditField                   matlab.ui.control.NumericEditField
        highEditFieldLabel              matlab.ui.control.Label
        lowEditField                    matlab.ui.control.NumericEditField
        lowEditFieldLabel               matlab.ui.control.Label
        Button_SelectDICpattern_AutoROI  matlab.ui.control.Button
        graphsegmenterTab               matlab.ui.container.Tab
        GenerateROIButton               matlab.ui.control.Button
        selectbackgroundButton          matlab.ui.control.Button
        selectforegroundButton          matlab.ui.control.Button
        ModifyROIPanel                  matlab.ui.container.Panel
        Button_clearMask                matlab.ui.control.Button
        Button_makeMask                 matlab.ui.control.Button
        Button_elip_neg                 matlab.ui.control.Button
        Button_poly_neg                 matlab.ui.control.Button
        Button_elip_pos                 matlab.ui.control.Button
        Button_poly_pos                 matlab.ui.control.Button
        Button_cir_neg                  matlab.ui.control.Button
        Button_rect_neg                 matlab.ui.control.Button
        SubtractmaskLabel               matlab.ui.control.Label
        Button_cir_pos                  matlab.ui.control.Button
        AddmaskLabel                    matlab.ui.control.Label
        Button_rect_pos                 matlab.ui.control.Button
        Button_LoadROI_single           matlab.ui.control.Button
        UITable_ImageList_ROI           matlab.ui.control.Table
        Button_LoadROI_set              matlab.ui.control.Button
        ImageListLabel                  matlab.ui.control.Label
        UIAxes_ROI                      matlab.ui.control.UIAxes
        StartTab                        matlab.ui.container.Tab
        AnalysisTabGroup_SERIAL         matlab.ui.container.TabGroup
        AnalysislistTab_SERIAL          matlab.ui.container.Tab
        AnalysistypeSERIALTrackLabel    matlab.ui.control.Label
        StartnewanalysisButton_SERIAL   matlab.ui.control.Button
        AnalysisnameEditField_SERIAL    matlab.ui.control.EditField
        AnalysisnameEditFieldLabel_2    matlab.ui.control.Label
        PtpTab_SERIAL                   matlab.ui.container.Tab
        TabGroup4_2                     matlab.ui.container.TabGroup
        BeadParamsTab_2                 matlab.ui.container.Tab
        PSFtypeDropDown_2_SERIAL        matlab.ui.control.DropDown
        PSFtypeDropDown_2Label          matlab.ui.control.Label
        BeadColorDropDown_2_SERIAL      matlab.ui.control.DropDown
        BeadColorDropDown_2Label        matlab.ui.control.Label
        distMissingEditField_2_SERIAL   matlab.ui.control.NumericEditField
        distMissingEditField_2Label     matlab.ui.control.Label
        maxwinsizeEditField_4_SERIAL    matlab.ui.control.NumericEditField
        maxwinsizeEditFieldLabel        matlab.ui.control.Label
        maxbeadsizeEditField_4_SERIAL   matlab.ui.control.NumericEditField
        maxbeadsizeEditField_4Label     matlab.ui.control.Label
        minbeadsizeEditField_2_SERIAL   matlab.ui.control.NumericEditField
        minbeadsizeEditField_2Label     matlab.ui.control.Label
        avgbeadsizeEditField_2_SERIAL   matlab.ui.control.NumericEditField
        avgbeadsizeEditField_2Label     matlab.ui.control.Label
        beadthresholdEditField_2_SERIAL  matlab.ui.control.NumericEditField
        beadthresholdEditField_2Label   matlab.ui.control.Label
        TrackOptsTab_2_SERIAL           matlab.ui.container.Tab
        tstepEditField_2_SERIAL         matlab.ui.control.NumericEditField
        tstepEditField_2Label           matlab.ui.control.Label
        xstepEditField_2_SERIAL         matlab.ui.control.NumericEditField
        xstepEditField_2Label           matlab.ui.control.Label
        MaxstrainNeighborsEditField_2_SERIAL  matlab.ui.control.NumericEditField
        MaxstrainNeighborsEditField_2Label  matlab.ui.control.Label
        ADMMthreshEditField_2_SERIAL    matlab.ui.control.NumericEditField
        ADMMthreshEditField_2Label      matlab.ui.control.Label
        MaxADMMiterEditField_2_SERIAL   matlab.ui.control.NumericEditField
        MaxADMMiterEditField_2Label     matlab.ui.control.Label
        outlierthreshEditField_2_SERIAL  matlab.ui.control.NumericEditField
        outlierthreshEditField_2Label   matlab.ui.control.Label
        regularizationcoefEditField_2_SERIAL  matlab.ui.control.NumericEditField
        regularizationcoefEditField_2Label  matlab.ui.control.Label
        GlobalstepsolverDropDown_2_SERIAL  matlab.ui.control.DropDown
        GlobalstepsolverDropDown_2Label  matlab.ui.control.Label
        MinNeighborsEditField_2_SERIAL  matlab.ui.control.NumericEditField
        MinNeighborsEditField_2Label    matlab.ui.control.Label
        MaxNeighborsEditField_2_SERIAL  matlab.ui.control.NumericEditField
        MaxNeighborsEditField_2Label    matlab.ui.control.Label
        SearchFieldEditField_2_SERIAL   matlab.ui.control.NumericEditField
        SearchFieldEditField_2Label     matlab.ui.control.Label
        TrackingModeDropDown_2_SERIAL   matlab.ui.control.DropDown
        TrackingModeDropDown_2Label     matlab.ui.control.Label
        ParticleTypeDropDown_2_SERIAL   matlab.ui.control.DropDown
        ParticleTypeDropDown_2Label     matlab.ui.control.Label
        DetectionmethodDropDown_2_SERIAL  matlab.ui.control.DropDown
        DetectionmethodDropDown_2Label  matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        IDG_TrackedImage_SERIAL         matlab.ui.control.UIAxes
        IDG_ReferenceImage_SERIAL       matlab.ui.control.UIAxes
        StartsearchButton_SERIAL        matlab.ui.control.Button
        SetparametersTab_SERIAL         matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        BeadParamsTab                   matlab.ui.container.Tab
        PSFtypeDropDown_SERIAL          matlab.ui.control.DropDown
        PSFtypeDropDownLabel            matlab.ui.control.Label
        BeadColorDropDown_SERIAL        matlab.ui.control.DropDown
        BeadColorDropDownLabel          matlab.ui.control.Label
        distMissingEditField_SERIAL     matlab.ui.control.NumericEditField
        distMissingEditFieldLabel       matlab.ui.control.Label
        maxwinsizeEditField_1_SERIAL    matlab.ui.control.NumericEditField
        maxwinsizeLabel                 matlab.ui.control.Label
        maxbeadsizeEditField_SERIAL     matlab.ui.control.NumericEditField
        maxbeadsizeEditFieldLabel       matlab.ui.control.Label
        minbeadsizeEditField_SERIAL     matlab.ui.control.NumericEditField
        minbeadsizeEditFieldLabel       matlab.ui.control.Label
        avgbeadsizeEditField_SERIAL     matlab.ui.control.NumericEditField
        avgbeadsizeEditFieldLabel       matlab.ui.control.Label
        beadthresholdEditField_SERIAL   matlab.ui.control.NumericEditField
        beadthresholdEditFieldLabel     matlab.ui.control.Label
        TrackOptsTab                    matlab.ui.container.Tab
        Maxstrain_fos_SERIAL            matlab.ui.control.NumericEditField
        VirtualstraingausesizeLabel     matlab.ui.control.Label
        UsepreviousresultstodetectnewparticlesCheckBox_SERIAL  matlab.ui.control.CheckBox
        TrackingModeDropDown_SERIAL     matlab.ui.control.DropDown
        TrackingModeDropDownLabel       matlab.ui.control.Label
        ParticleTypeDropDown_SERIAL     matlab.ui.control.DropDown
        ParticleTypeDropDownLabel       matlab.ui.control.Label
        localsolvermethodDropDown_SERIAL  matlab.ui.control.DropDown
        localsolvermethodDropDownLabel  matlab.ui.control.Label
        tstepEditField_SERIAL           matlab.ui.control.NumericEditField
        tstepEditFieldLabel             matlab.ui.control.Label
        xstepEditField_SERIAL           matlab.ui.control.NumericEditField
        xstepEditFieldLabel             matlab.ui.control.Label
        MaxstrainNeighborsEditField_SERIAL  matlab.ui.control.NumericEditField
        MaxstrainNeighborsLabel         matlab.ui.control.Label
        ADMMthreshEditField_SERIAL      matlab.ui.control.NumericEditField
        ADMMthreshEditFieldLabel        matlab.ui.control.Label
        MaxADMMiterEditField_SERIAL     matlab.ui.control.NumericEditField
        MaxADMMiterEditFieldLabel       matlab.ui.control.Label
        outlierthreshEditField_SERIAL   matlab.ui.control.NumericEditField
        outlierthreshEditFieldLabel     matlab.ui.control.Label
        regularizationcoefEditField_SERIAL  matlab.ui.control.NumericEditField
        regularizationcoefEditFieldLabel  matlab.ui.control.Label
        GlobalstepsolverDropDown_SERIAL  matlab.ui.control.DropDown
        GlobalstepsolverDropDownLabel   matlab.ui.control.Label
        MinNeighborsEditField_SERIAL    matlab.ui.control.NumericEditField
        MinNeighborsEditFieldLabel      matlab.ui.control.Label
        MaxNeighborsEditField_SERIAL    matlab.ui.control.NumericEditField
        MaxNeighborsEditFieldLabel      matlab.ui.control.Label
        SearchFieldEditField_SERIAL     matlab.ui.control.NumericEditField
        SearchFieldEditFieldLabel       matlab.ui.control.Label
        DetectionmethodDropDown_SERIAL  matlab.ui.control.DropDown
        DetectionmethodDropDownLabel    matlab.ui.control.Label
        PlotResults_SERIAL              matlab.ui.container.Panel
        A_disp_SERIAL                   matlab.ui.control.UIAxes
        A_TP_SERIAL                     matlab.ui.control.UIAxes
        StartAnalysis_SERIAL            matlab.ui.control.Button
        AnalysisTabGroup_ALDIC          matlab.ui.container.TabGroup
        AnalysislistTab_ALDIC           matlab.ui.container.Tab
        AnalysistypeALDICLabel          matlab.ui.control.Label
        StartnewanalysisButton_ALDIC    matlab.ui.control.Button
        AnalysisnameEditField_ALDIC     matlab.ui.control.EditField
        AnalysisnameEditFieldLabel      matlab.ui.control.Label
        SetinitialdisplacementguessTab_ALDIC  matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        IDG_DeformedImage_ALDIC         matlab.ui.control.UIAxes
        IDG_ReferenceImage_ALDIC        matlab.ui.control.UIAxes
        maxdisplacementEditField_ALDIC  matlab.ui.control.NumericEditField
        maxdisplacementEditFieldLabel   matlab.ui.control.Label
        SearchsettingsPanel_ALDIC       matlab.ui.container.Panel
        searchstepsizeEditField_ALDIC   matlab.ui.control.NumericEditField
        searchstepsizeEditFieldLabel    matlab.ui.control.Label
        searchregionsizeEditField_ALDIC  matlab.ui.control.NumericEditField
        searchregionsizeEditFieldLabel  matlab.ui.control.Label
        StartsearchButton_ALDIC         matlab.ui.control.Button
        RadioGroup_InitialguessMethod_ALDIC  matlab.ui.container.ButtonGroup
        Radio_Manualseed_ALDIC          matlab.ui.control.RadioButton
        Radio_Wholefieldsearch_ALDIC    matlab.ui.control.RadioButton
        Radio_Multigridsearch_ALDIC     matlab.ui.control.RadioButton
        SetDICparametersTab_ALDIC       matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        A_Vdisp_ALDIC                   matlab.ui.control.UIAxes
        A_Udisp_ALDIC                   matlab.ui.control.UIAxes
        StartAnalysis_ALDIC             matlab.ui.control.Button
        InitialguessoptionsPanel_ALDIC  matlab.ui.container.Panel
        UsemostrecentframeasguessCheckBox_ALDIC  matlab.ui.control.CheckBox
        UsePODCheckBox_ALDIC            matlab.ui.control.CheckBox
        ALDICOptionsPanel_ALDIC         matlab.ui.container.Panel
        muEditField_ALDIC               matlab.ui.control.NumericEditField
        muEditFieldLabel_2              matlab.ui.control.Label
        toleranceEditField_ALDIC        matlab.ui.control.NumericEditField
        toleranceEditFieldLabel_2       matlab.ui.control.Label
        stepsizeEditField_ALDIC         matlab.ui.control.NumericEditField
        stepsizeEditFieldLabel_2        matlab.ui.control.Label
        windowsizeEditField_ALDIC       matlab.ui.control.NumericEditField
        windowsizeEditFieldLabel_2      matlab.ui.control.Label
        Subproblem1MethodButtonGroup_ALDIC  matlab.ui.container.ButtonGroup
        GaussNewtonButton_ALDIC         matlab.ui.control.RadioButton
        LevenbergMarquardtButton_ALDIC  matlab.ui.control.RadioButton
        Subproblem2MethodButtonGroup_ALDIC  matlab.ui.container.ButtonGroup
        FiniteElementMethodButton_ALDIC  matlab.ui.control.RadioButton
        FiniteDifferenceButton_ALDIC    matlab.ui.control.RadioButton
        numberofparallelpoolsSpinner_ALDIC  matlab.ui.control.Spinner
        numberofparallelpoolsSpinnerLabel  matlab.ui.control.Label
        DisplacementsTab                matlab.ui.container.Tab
        AnalyzedImagesPanel_2           matlab.ui.container.Panel
        DisplacementTree                matlab.ui.container.Tree
        Node_2                          matlab.ui.container.TreeNode
        Node2_2                         matlab.ui.container.TreeNode
        Node3_2                         matlab.ui.container.TreeNode
        Node4_2                         matlab.ui.container.TreeNode
        StrainsTab                      matlab.ui.container.Tab
        StrainCurrentPlotLabel          matlab.ui.control.Label
        PreviousImageButton             matlab.ui.control.Button
        NextImageButton                 matlab.ui.control.Button
        StartAnalysis_Strain            matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        StraincomputationoptionsPanel   matlab.ui.container.Panel
        DisplayStrain                   matlab.ui.container.Panel
        PlotStrainDropDown              matlab.ui.control.DropDown
        PlotStrainDropDownLabel         matlab.ui.control.Label
        StrainTypePannel                matlab.ui.container.Panel
        StraintypeDropDown              matlab.ui.control.DropDown
        StraintypeDropDownLabel         matlab.ui.control.Label
        WindowSizeEditField_StrainPlane  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        DisplacementsmoothingEditField  matlab.ui.control.NumericEditField
        DisplacementsmoothingEditFieldLabel  matlab.ui.control.Label
        StraincomputationmethodDropDown  matlab.ui.control.DropDown
        StraincomputationmethodDropDownLabel  matlab.ui.control.Label
        ColorbaroptionsPanel            matlab.ui.container.Panel
        upperrangeEditField             matlab.ui.control.NumericEditField
        upperrangeEditFieldLabel        matlab.ui.control.Label
        lowerrangeEditField             matlab.ui.control.NumericEditField
        lowerrangeEditFieldLabel        matlab.ui.control.Label
        SegmentcountEditField           matlab.ui.control.NumericEditField
        SegmentcountEditFieldLabel      matlab.ui.control.Label
        strainmeanEditField             matlab.ui.control.EditField
        strainmeanEditFieldLabel        matlab.ui.control.Label
        strainrangeEditField            matlab.ui.control.EditField
        strainrangeEditFieldLabel       matlab.ui.control.Label
        IncludecolorbarCheckBox         matlab.ui.control.CheckBox
        ExportimagesPanel               matlab.ui.container.Panel
        ExporttypeDropDown              matlab.ui.control.DropDown
        ExporttypeDropDownLabel         matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ScalebaroptionsPanel            matlab.ui.container.Panel
        IncludetextCheckBox             matlab.ui.control.CheckBox
        fontsizeEditField               matlab.ui.control.NumericEditField
        fontsizeEditFieldLabel          matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        thicknesspxEditField            matlab.ui.control.NumericEditField
        thicknesspxEditFieldLabel       matlab.ui.control.Label
        lengthpxEditField               matlab.ui.control.NumericEditField
        lengthpxEditFieldLabel          matlab.ui.control.Label
        ylocationEditField              matlab.ui.control.NumericEditField
        ylocationEditFieldLabel         matlab.ui.control.Label
        xlocationEditField              matlab.ui.control.NumericEditField
        xlocationEditFieldLabel         matlab.ui.control.Label
        IncludescalebarCheckBox         matlab.ui.control.CheckBox
        OverlayoptionsPanel             matlab.ui.container.Panel
        OverlayStrainMapButtonGroup     matlab.ui.container.ButtonGroup
        DonotoverlaydataButton          matlab.ui.control.RadioButton
        OverlaydeformedimagesButton     matlab.ui.control.RadioButton
        OverlayontoreferenceimageButton  matlab.ui.control.RadioButton
        alphaEditField                  matlab.ui.control.EditField
        alphaEditFieldLabel             matlab.ui.control.Label
        OptionsButtonGroup              matlab.ui.container.ButtonGroup
        ExportButton_2                  matlab.ui.control.ToggleButton
        ColorbarButton                  matlab.ui.control.ToggleButton
        ScalebarButton                  matlab.ui.control.ToggleButton
        OverlayButton                   matlab.ui.control.ToggleButton
        MethodsButton                   matlab.ui.control.ToggleButton
        AnalyzedImagesPanel             matlab.ui.container.Panel
        StrainTree                      matlab.ui.container.Tree
        objName1Node                    matlab.ui.container.TreeNode
        Node_6                          matlab.ui.container.TreeNode
        Node_7                          matlab.ui.container.TreeNode
        Node_8                          matlab.ui.container.TreeNode
        objName2Node                    matlab.ui.container.TreeNode
        Node_4                          matlab.ui.container.TreeNode
        Node_5                          matlab.ui.container.TreeNode
        Node_3                          matlab.ui.container.TreeNode
        StrainMap                       matlab.ui.control.UIAxes
        ExportImportDataTab             matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        SelectAnalysisListBox_Export    matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_5    matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        
        % ImageLoad 
        ImageFilePath = []; ImageFileNames = []; ImgSelected = 1; RefSelction = [];
        Imageum2pxratio = [];
        AllImages={};
        % ROI creation
        ROIFilePath = []; ROIFileNames = []; ROISelected = 1;
        ROIMask = []; AllROIMasks = {};
           % Auto ROI filter based
           filterRange = []; filterOrder = [];
           % Auto ROI graph based
           Background = []; Foreground = [];
           
        % this stores the type of DIC analysis that is being preformed on the data   
        Analysistype = [];
        SavedAnalysis = {};
        CurrentAnalysis = 0;
        CurrentAnalysisNum = 0;
        CurrentAnalysisName = '';
        CurrentSelectedAnalysis = 1;
        
        CurrentStrainDataSelection = {};
        CurrentStrainImage = 1;
        CurrentStrainPlotType = 'vonMises';
        
        filename = 'MyFileName.mat'
        
        % handle some ND2 stuff
        ND2_info = {};
        IMobj_info = {};
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_ALDIC
        function Button_ALDICPushed(app, event)
            % setup the options for ALDIC
            app.TabGroup_Load2D.Position = [1,1,1200,617];
            app.AnalysisTabGroup_ALDIC.Position = [1,1,1200,617];
            app.TabGroup.SelectedTab = app.LoadExperimentDataTab;
            
          
            app.Analysistype = 'ALDIC';
        end

        % Button pushed function: Button_LoadImages
        function Button_LoadImagesPushed(app, event)
            % open a browser window to select images and return image
            % filepaths and names
            [app.ImageFilePath, app.ImageFileNames] = Func_ImageLoad('multi','on');
            
            % clear the workspace
            workspace = 'loadImages';
            script_resetworkspace
            
            IMs =app.ImageFileNames;
            
            % set the data in the UItable_ImageList to the imagefiles
            % selected
            app.UITable_ImageList.Data = [];
            app.UITable_ImageList.Data = IMs';
            app.UITable_ImageList_ROI.Data = [];
            % set the imagelist in the ROI field as well
            app.UITable_ImageList_ROI.Data = IMs';
            % set the reference image to false for all images, then true
            % for the first 
            app.UITable_ImageList.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));
            app.UITable_ImageList.Data(1,2) = num2cell(true);
            % set the ROI list to have no associated ROI's
            app.UITable_ImageList_ROI.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));

            % set the image in the reference viewer to be the first image
            func_PlaceFigure(app.UIAxes_RefImg,im2gray(imread(app.ImageFilePath{1})));
            % set the image in the deformed viewer to be the second image
            func_PlaceFigure(app.UIAxes_DefImg,im2gray(imread(app.ImageFilePath{2})));
            
            % set the ROI axes to the first image
            IM = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
            
            % initialize an array of ROI masks
            app.AllROIMasks = {};
            for i=1:length(app.ImageFilePath)
                app.AllROIMasks{i}=[];
                %load all Images to memory
                app.AllImages{i} = double(im2gray(imread(app.ImageFilePath{i})));
            end
            
        end

        % Cell selection callback: UITable_ImageList
        function UITable_ImageListCellSelection(app, event)
            % get the index of the image 
            indices = event.Indices;
            app.ImgSelected = indices;
            % temp variable for the image list
            IMLst = app.ImageFilePath;
            refLst = app.UITable_ImageList.Data(:,2)';
            app.RefSelction = refLst;
            % set the image in the reference viewer to be the first image
            % that is true wrt the currently selected image
            firstRef_Img = [fliplr(IMLst(1:indices(1,1))) , IMLst(indices(1,1)+1:end)];
            firstRef_bool = [fliplr(refLst(1:indices(1,1))) , refLst(indices(1,1)+1:end)];
            refImg = firstRef_Img{find(cell2mat(firstRef_bool)==1,1)};
            defImg = IMLst{indices(1,1)};
            
            func_PlaceFigure(app.UIAxes_RefImg,im2gray(imread(refImg)));
            func_PlaceFigure(app.UIAxes_DefImg,im2gray(imread(defImg)));

        end

        % Button pushed function: Button_LoadROI_set
        function Button_LoadROI_setPushed(app, event)
            [app.ROIFilePath, app.ROIFileNames] = Func_ImageLoad('multi','on');
            % check to see that the number of selected images is the same
            % as the number of images 
            if isequal(length(app.ImageFileNames),length(app.ROIFileNames))
                
                Im = im2gray(imread(app.ImageFilePath{1}));
                
                for i=1:numel(app.ROIFilePath)
                    mask = im2gray(double(imread(app.ROIFilePath{i})));
                    % ensure mask is binary
                    mask = imbinarize(mask);
                    
                    % make sure mask is the same size as the image
                    if isequal(size(Im),size(mask))
                        app.AllROIMasks{i} = logical(mask);
                    else
                        return
                    end
                end
                
                % now load that ROI as an alpha map over top of the
                % 1st image
                foreground = app.AllROIMasks{1};
                ROIfig = imageoverlay2(Im,foreground,0.5);
                func_PlaceFigure(app.UIAxes_ROI,ROIfig);
                
                % set all images to have ROI
                app.UITable_ImageList_ROI.Data(:,2) = num2cell(true(1,length(app.ImageFileNames)));
            end
            
        end

        % Button pushed function: Button_LoadROI_single
        function Button_LoadROI_singlePushed(app, event)
            % get the currently selected image on the list. if no image is
            % selected then default to 1
            [ROIfp, ROIfn] = Func_ImageLoad('multi','off');
            
            app.ROIFilePath{app.ROISelected(1)} = ROIfp;
            app.ROIFileNames{app.ROISelected(1)} = ROIfn;
            
            % load the image and mask files
            Im = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            mask = im2gray(double(imread(app.ROIFilePath{app.ROISelected(1)})));
            
            % ensure mask is binary
            mask = imbinarize(mask);
            
            % make sure mask is the same size as the image
            if isequal(size(Im),size(mask))
                app.AllROIMasks{app.ROISelected(1)} = logical(mask);
            else
                return
            end
            
            app.UITable_ImageList_ROI.Data(app.ROISelected(1),2) = num2cell(true);
            
            ROIfig = imageoverlay2(im,mask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Cell selection callback: UITable_ImageList_ROI
        function UITable_ImageList_ROICellSelection(app, event)
            indices = event.Indices;
            app.ROISelected = indices;
            
            
            if isempty(app.AllROIMasks{app.ROISelected(1)})
                background = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
                if isempty(app.ROIMask)
                    foreground = zeros(size(background));
                else
                    foreground = app.ROIMask;
                end
                
                ROIfig = imageoverlay2(background,foreground,0.5);
                func_PlaceFigure(app.UIAxes_ROI,ROIfig);
            else
                background = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
                foreground = app.AllROIMasks{app.ROISelected(1)};
                ROIfig = imageoverlay2(background,foreground,0.5);
                func_PlaceFigure(app.UIAxes_ROI,ROIfig);
                app.ROIMask = foreground;
            end

        end

        % Button pushed function: Button_SelectDICpattern_AutoROI
        function Button_SelectDICpattern_AutoROIPushed(app, event)

            Im = im2gray(imread(app.ImageFilePath{app.ROISelected(1)})); % the the Image associated with the currently selected ROI image list
            script_fftCompare % <- mask   
            ROIfig = imageoverlay2(Im,mask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
            
        end

        % Callback function
        function Button_Generate_AutoROIPushed(app, event)
            Im = im2gray(imread(app.ImageFilePath{app.ROISelected(1)})); % the the Image associated with the currently selected ROI image list
            script_fftCompare
            ROIfig = imageoverlay2(Im,mask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Button pushed function: selectforegroundButton
        function selectforegroundButtonPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','Speckle_Pattern','Color','g');
        end

        % Button pushed function: selectbackgroundButton
        function selectbackgroundButtonPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','Background','Color','r');
        end

        % Button pushed function: GenerateROIButton
        function GenerateROIButtonPushed(app, event)
            
            Im = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            app.Background = zeros(size(Im));
            app.Foreground = zeros(size(Im));
            
            for i=1:numel(app.UIAxes_ROI.Children)
                ax = app.UIAxes_ROI.Children(i);
                try
                    if isequal(ax.Label,'Background')
                        mask = createMask(ax,Im);
                        app.Background = app.Background + mask;
    
                    elseif isequal(ax.Label,'Speckle_Pattern')
                        mask = createMask(ax,Im);
                        app.Foreground = app.Foreground + mask;
    
                    end
                catch
                end
            end
            
        [L,~] = superpixels(Im,500);
        BW = lazysnapping(Im,L,app.Foreground,app.Background);    
        ROIfig = imageoverlay2(Im,BW,0.5);
        func_PlaceFigure(app.UIAxes_ROI,ROIfig);    
            
        end

        % Button pushed function: Button_rect_pos
        function Button_rect_posPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_cir_pos
        function Button_cir_posPushed(app, event)
            drawcircle(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_elip_pos
        function Button_elip_posPushed(app, event)
            drawellipse(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_poly_pos
        function Button_poly_posPushed(app, event)
            drawpolygon(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_rect_neg
        function Button_rect_negPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_cir_neg
        function Button_cir_negPushed(app, event)
            drawcircle(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_elip_neg
        function Button_elip_negPushed(app, event)
            drawellipse(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_poly_neg
        function Button_poly_negPushed(app, event)
            drawpolygon(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_ClearROI
        function Button_ClearROIPushed(app, event)

            % set all images to have ROI
            for i=1:length(app.ROISelected)
                app.AllROIMasks{app.ROISelected(i)} = [];
                app.UITable_ImageList_ROI.Data(app.ROISelected(i),2) = num2cell(false(1,1));
            end
            
            IM = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Button pushed function: Button_makeMask
        function Button_makeMaskPushed(app, event)
            % use the current image to get the size and to use it as a
            % background
            Im = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            
            % initialize the masks
            add2image = zeros(size(Im));
            subfromimage = zeros(size(Im));
            
            % go through the current axis and see if there are any shapes
            % drawn on it, if so use those shapes to make a mask
            for i=1:numel(app.UIAxes_ROI.Children)
                % the children are the rectangles, circles etc select one
                % of them
                ax = app.UIAxes_ROI.Children(i);
                % need to use a try and catch because some elements of the
                % axis may not have labels
                try
                    if isequal(ax.Label,'add') % if one of the objects has this label
                        mask = createMask(ax,Im);
                        add2image = add2image + mask;
                        add2image(add2image>1)=1; % if a mask overlaps then we dont care
                    elseif isequal(ax.Label,'subtract')
                        mask = createMask(ax,Im);
                        subfromimage = subfromimage + mask;
                        subfromimage(subfromimage>1)=1; % if a mask overlaps then we dont care
                    end
                catch
                    % do nothing
                end
            end
            
            % from all of the add and subtract rectangles, make a mask
            app.ROIMask = app.ROIMask + add2image;
            app.ROIMask(app.ROIMask>1) = 1;
            app.ROIMask = app.ROIMask - subfromimage;
            app.ROIMask(app.ROIMask<0) = 0;
            
            % overlay that mask and display it
            ROIfig = imageoverlay2(Im,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig); 
            
        end

        % Button pushed function: Button_Applytoselected
        function Button_ApplytoselectedPushed(app, event)
            selected = app.ROISelected(:,1);
            for i=1:numel(selected)
                app.AllROIMasks{selected(i)} = logical(app.ROIMask);
                app.UITable_ImageList_ROI.Data(selected(i),2) = num2cell(true(1,1));
            end
        end

        % Button pushed function: Button_Applytoall
        function Button_ApplytoallPushed(app, event)
            % set all images to have the current ROI 
            for i=1:numel(app.ImageFilePath)
                app.AllROIMasks{i} = logical(app.ROIMask);
                app.UITable_ImageList_ROI.Data(i,2) = num2cell(true(1,1));
            end
        end

        % Button pushed function: Button_ClearAll
        function Button_ClearAllPushed(app, event)
            % cleae all ROIs
            for i=1:numel(app.ImageFilePath)
                app.AllROIMasks{app.ROISelected(i)} = [];
                app.UITable_ImageList_ROI.Data(app.ROISelected(i),2) = num2cell(false(1,1));
            end
            
            IM = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);

        end

        % Button pushed function: Button_clearMask
        function Button_clearMaskPushed(app, event)
            IM = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Button pushed function: Button_DICButton
        function Button_DICButtonPushed(app, event)
            app.Panel_DIC.Visible = true;
            app.Panel_TPT.Visible = false;
            
        end

        % Selection changed function: 
        % RadioGroup_InitialguessMethod_ALDIC
        function RadioGroup_InitialguessMethod_ALDICSelectionChanged(app, event)
            selectedButton = app.RadioGroup_InitialguessMethod_ALDIC.SelectedObject;
            
            if isequal(selectedButton,'Manual seed')
                
            end
            
        end

        % Value changed function: AnalysisnameEditField_ALDIC
        function AnalysisnameEditField_ALDICValueChanged(app, event)

            app.StartnewanalysisButton_ALDIC.Enable = true;
        end

        % Button pushed function: StartnewanalysisButton_ALDIC
        function StartnewanalysisButton_ALDICPushed(app, event)
            app.AnalysisnameEditField_ALDIC.Value = '';
            app.StartnewanalysisButton_ALDIC.Enable = false;
            app.SetDICparametersTab_ALDIC.Title = app.Analysistype;

            % what type of DIC object?
            switch app.Analysistype 
                case 'ALDIC'
                    obj = ALDIC();
                case 'STAQ'
                    obj = STAQ();
            end
            
            obj.Name = app.CurrentAnalysisName;
            app.CurrentAnalysis = obj;
            app.CurrentAnalysisNum = size(app.SavedAnalysis,1)+1;
            
            pos = size(app.SavedAnalysis,1)+1;
            
            % store object in an array
            app.SavedAnalysis{pos,1} = obj;
            app.SavedAnalysis{pos,2} = obj.Name;
            app.SavedAnalysis{pos,3} = app.CurrentAnalysisNum;
            app.SavedAnalysis{pos,4} = obj.Type;
            
            % make the next tab the current tab
            app.AnalysisTabGroup_ALDIC.SelectedTab = app.SetinitialdisplacementguessTab_ALDIC;
            
            % update the list of analysis objects 
            script_UpdateListOfObjects
            
            app.DisplacementTree.Children
            
        end

        % Button pushed function: StartsearchButton_ALDIC
        function StartsearchButton_ALDICPushed(app, event)
            switch app.Analysistype 
                case 'ALDIC'
                    InitialSearch(app);
                case 'STAQ'
                    InitialSearchSTAQ(app);
            end
           
        end

        % Button pushed function: StartAnalysis_ALDIC
        function StartAnalysis_ALDICPushed(app, event)
           switch app.Analysistype 
                case 'ALDIC'
                    StartALDIC(app);
                case 'STAQ'
                    StartSTAQ(app);
            end 
          
        end

        % Value changed function: searchregionsizeEditField_ALDIC
        function searchregionsizeEditField_ALDICValueChanged(app, event)
            value = app.searchregionsizeEditField_ALDIC.Value;
            app.windowsizeEditField_ALDIC.Value = value;
        end

        % Value changed function: searchstepsizeEditField_ALDIC
        function searchstepsizeEditField_ALDICValueChanged(app, event)
            value = app.searchstepsizeEditField_ALDIC.Value;
            app.stepsizeEditField_ALDIC.Value = value;
        end

        % Value changing function: AnalysisnameEditField_ALDIC
        function AnalysisnameEditField_ALDICValueChanging(app, event)
            changingValue = event.Value;
            app.StartnewanalysisButton_ALDIC.Enable = true;
            app.CurrentAnalysisName = changingValue;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                obj = app.SavedAnalysis{app.CurrentSelectedAnalysis};
                obj.save(app.filename)
            catch ME
                disp('Could not save for whatever reason')
            end
        end

        % Value changed function: SelectAnalysisListBox_Export
        function SelectAnalysisListBox_ExportValueChanged(app, event)
            value = app.SelectAnalysisListBox_Export.Value;
            values = strsplit(value);
            for i=1:length(app.SavedAnalysis)
                obj = app.SavedAnalysis{i};
                try
                    Name = obj.Name;
                catch ME
                    Name = 'Not Possible Because Of Spaces';
                end
                if isequal(Name, values{2})
                    app.CurrentSelectedAnalysis = i;
                end
            end
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
                try 
                    condition = isequal(changingValue(end-3:end),'.mat');
                catch ME
                    condition = false;
                end
            
                if condition
                    app.filename = changingValue;
                else
                    app.filename = [changingValue '.mat'];
                end
                
                app.filename
                
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.EditField.Value = app.filename;
        end

        % Value changed function: InputmtopixelratioEditField
        function InputmtopixelratioEditFieldValueChanged(app, event)
            value = app.InputmtopixelratioEditField.Value;
            app.Imageum2pxratio = value;
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionsButtonGroup.SelectedObject;
            
            app.StraincomputationoptionsPanel.Visible = false;
            app.ColorbaroptionsPanel.Visible = false;
            app.ExportimagesPanel.Visible = false;
            app.ScalebaroptionsPanel.Visible = false;
            app.OverlayoptionsPanel.Visible = false;
            
            switch selectedButton.Text
                case 'Methods'
                    app.StraincomputationoptionsPanel.Visible = true;
                    
                case 'Export'
                    app.ExportimagesPanel.Visible = true;
                    
                case 'Colorbar'
                    app.ColorbaroptionsPanel.Visible = true;
                    
                case 'Scalebar'
                    app.ScalebaroptionsPanel.Visible = true;
                    
                case 'Overlay'
                    app.OverlayoptionsPanel.Visible = true;

            end
            
        end

        % Button pushed function: StartAnalysis_Strain
        function StartAnalysis_StrainButtonPushed(app, event)
            % get the currently selected nodes
            
            % collect the selected nodes and get that nodes object
            % save both of those 
            
            try
                app.CurrentStrainDataSelection = func_getStrainSelection(app);
                Data = app.CurrentStrainDataSelection;
                script_startStrainAnalysis

            catch ME
                disp('Something went wrong with the Strain analysis')
            end
            
        end

        % Value changed function: StraintypeDropDown
        function StraintypeDropDownValueChanged(app, event)
            
        end

        % Value changed function: StraincomputationmethodDropDown
        function StraincomputationmethodDropDownValueChanged(app, event)
            value = app.StraincomputationmethodDropDown.Value;
            if isequal(value,'Plane Fitting')
                app.StraintypeDropDown.Position = [35,172,174,22];
                app.WindowSizeEditField_StrainPlane.Visible = true;
            else
                app.StraintypeDropDown.Position = [35,234,174,22];
                app.WindowSizeEditField_StrainPlane.Visible = false;
            end
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            app.CurrentStrainDataSelection = func_getStrainSelection(app);

            if isempty(app.CurrentStrainDataSelection)
                func_presentError(app,'no strain data selected');
            else
                CSI = app.CurrentStrainImage+1;
                if CSI >= size(app.CurrentStrainDataSelection,1)
                    CSI = 1; % reset the current selection to the first value
                end

                
                Data = app.CurrentStrainDataSelection(CSI,:);
                script_startStrainAnalysis

                app.CurrentStrainImage = CSI;
            end
            
            
        end

        % Button pushed function: PreviousImageButton
        function PreviousImageButtonPushed(app, event)
            app.CurrentStrainDataSelection = func_getStrainSelection(app);

            if isempty(app.CurrentStrainDataSelection)
                func_presentError(app,'no strain data selected');
            else
                CSI = app.CurrentStrainImage-1;
                if CSI <= 1
                    CSI = size(app.CurrentStrainDataSelection,1); % reset the current selection to the last value
                end

                Data = app.CurrentStrainDataSelection(CSI,:);
                script_startStrainAnalysis

                app.CurrentStrainImage = CSI;
            end

            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            load(fullfile(path,file));
            
            try
                %obj.ProgramFolder
                %obj.Type
                obj.Name;
                obj.SaveFolderPath = fullfile(path,file);
                
                pos = size(app.SavedAnalysis,1)+1;
                
                % scan trhough the saved analysis list for matching Name
                for p = 1:size(app.SavedAnalysis,1)
                    % if the parent node is the same as one of the listed
                    % analysis objects return the object
                    if isequal(app.SavedAnalysis{p,2},obj.Name)
                        obj.Name = [obj.Name '*'];
                    end
                end
                
                % store object in an array
                app.SavedAnalysis{pos,1} = obj;
                app.SavedAnalysis{pos,2} = obj.Name;
                app.SavedAnalysis{pos,3} = pos;
                app.SavedAnalysis{pos,4} = obj.Type;
                
                script_UpdateListOfObjects
                
                Data = {1,pos,'IM_001',obj.Name};
                app.CurrentStrainImage = 1;
                script_startStrainAnalysis
                
                
            catch ME
                disp('not a valid file')
            end


        end

        % Value changed function: PlotStrainDropDown
        function PlotStrainDropDownValueChanged(app, event)
            value = app.PlotStrainDropDown.Value;
            
            app.CurrentStrainPlotType = value;
            
            try
                Data = app.CurrentStrainDataSelection(app.CurrentStrainImage,:);
                script_startStrainAnalysis
            catch 
                
            end

        end

        % Button pushed function: Button_qDIC
        function Button_qDICPushed(app, event)
            % setup the options for ALDIC
            app.TabGroup_Load2D.Position = [1,1,1200,617];
            app.TabGroup_Load3D.Position = [1201,1,1200,617];
            app.AnalysisTabGroup_ALDIC.Position = [1,1,1200,617];
            app.AnalysisTabGroup_SERIAL.Position = [1201,1,1200,617];
            app.AnalysistypeSERIALTrackLabel.Text = 'Analysis type: SERIAL-Track 2D';
            app.TabGroup.SelectedTab = app.LoadExperimentDataTab;
            
            app.Analysistype = 'STAQ';
        end

        % Button pushed function: StartnewanalysisButton_SERIAL
        function StartnewanalysisButton_SERIALPushed(app, event)
            app.AnalysisnameEditField_SERIAL.Value = '';
            app.StartnewanalysisButton_SERIAL.Enable = false;
            
            % what type of DIC object?
            switch app.Analysistype 
                case 'SERIAL2D'
                    obj = SERIAL2D();
                case 'SERIAL3D'
                    obj = SERIAL3D();
            end
            
            obj.Name = app.CurrentAnalysisName;
            app.CurrentAnalysis = obj;
            app.CurrentAnalysisNum = size(app.SavedAnalysis,1)+1;
            
            pos = size(app.SavedAnalysis,1)+1;
            
            % store object in an array
            app.SavedAnalysis{pos,1} = obj;
            app.SavedAnalysis{pos,2} = obj.Name;
            app.SavedAnalysis{pos,3} = app.CurrentAnalysisNum;
            app.SavedAnalysis{pos,4} = obj.Type;
            
            % make the next tab the current tab
            app.AnalysisTabGroup_SERIAL.SelectedTab = app.PtpTab_SERIAL;
            
            % update the list of analysis objects 
            script_UpdateListOfObjects
            
            app.DisplacementTree.Children
        end

        % Button pushed function: Button_PT
        function Button_PTPushed(app, event)
            app.Panel_DIC.Visible = false;
            app.Panel_TPT.Visible = true;
        end

        % Button pushed function: Button_ST2D
        function Button_ST2DPushed(app, event)
            % setup the options for ALDIC
            app.TabGroup_Load2D.Position = [1,1,1200,617];
            app.TabGroup_Load3D.Position = [1201,1,1200,617];
            app.AnalysisTabGroup_SERIAL.Position = [1,1,1200,617];
            app.AnalysisTabGroup_ALDIC.Position = [1201,1,1200,617];
            app.TabGroup.SelectedTab = app.LoadExperimentDataTab;
            
            app.Analysistype = 'SERIAL2D';
        end

        % Button pushed function: StartAnalysis_SERIAL
        function StartAnalysis_SERIALButtonPushed(app, event)
            
            % what type of DIC object?
            switch app.Analysistype 
                case 'SERIAL2D'
                    Start_SERIAL2D(app);
                case 'SERIAL3D'
                    Start_SERIAL3D(app);
            end
        end

        % Value changed function: AnalysisnameEditField_SERIAL
        function AnalysisnameEditField_SERIALValueChanged(app, event)
            app.StartnewanalysisButton_SERIAL.Enable = true;
            
        end

        % Button pushed function: Button_LoadND2
        function Button_LoadND2Pushed(app, event)
            % clear the workspace
            workspace = 'loadImages';
            script_resetworkspace
            
            try% ask user to open file location
                filterspec = {'*.nd2'};
                [baseName, folder] = uigetfile(filterspec,'MultiSelect', 'on');
                
                % if there are multiple ND2 files then use a different
                % protocol
                if length(baseName)>1
                    fnames = fullfile(folder, baseName);
                    Script_LoadND2_MF
                    Script_getPlanes_MF
                else
                    fname = fullfile(folder, baseName);
                    Script_LoadND2_TS
                    Script_getPlanes_TS
                end
                
                app.AllImages = IMstacks;
                app.ND2_info = ND2s;
                app.IMobj_info = IMobjs;
                app.ImageFileNames = baseName;
            catch
            end
            
            IMs =app.ImageFileNames;
            
            % set the data in the UItable_ImageList to the imagefiles
            % selected
            app.UITable_ImageList_3D.Data = [];
            app.UITable_ImageList_3D.Data = IMs';
            app.UITable_ImageList_ROI_3D.Data = [];
            % set the imagelist in the ROI field as well
            app.UITable_ImageList_ROI_3D.Data = IMs';
            % set the reference image to false for all images, then true
            % for the first 
            app.UITable_ImageList_3D.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));
            app.UITable_ImageList_3D.Data(1,2) = num2cell(true);
            % set the ROI list to have no associated ROI's
            app.UITable_ImageList_ROI_3D.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));
            
            zscale = app.InputmtopixelratioZ_3D.Value;
            
            % set the image in the reference viewer to be the first image
            func_PlaceVol(app.UIAxes_RefVol_3D, app.AllImages{1} ,zscale,'Multi2D');
            % set the image in the deformed viewer to be the second image
            func_PlaceVol(app.UIAxes_DefVol_3D, app.AllImages{2} ,zscale,'Multi2D');
            
            % set the ROI axes to the first image
            IM = imadjust(func_project(app.AllImages{1},'XY'));
            app.ROIMask = ones(size(app.AllImages{1}));
            
            ROI = func_project(app.ROIMask,'XY');
            ROIfig = imageoverlay2(IM,ROI,0.5);
            func_PlaceFigure(app.UIAxes_ROI_3D,ROIfig);
            
            % initialize an array of ROI masks
            app.AllROIMasks = {};
            for i=1:length(IMs)
                app.AllROIMasks{i}=[];
            end
                
            
        end

        % Button pushed function: Button_ST3D
        function Button_ST3DPushed(app, event)
            % setup the options for ALDIC
            app.TabGroup_Load3D.Position = [1,1,1200,617];
            app.TabGroup_Load2D.Position = [1201,1,1200,617];
            app.AnalysisTabGroup_SERIAL.Position = [1,1,1200,617];
            app.AnalysisTabGroup_ALDIC.Position = [1201,1,1200,619];
            
            app.AnalysistypeSERIALTrackLabel.Text = 'Analysis type: SERIAL-Track 3D';
            app.TabGroup.SelectedTab = app.LoadExperimentDataTab;
            
            app.Analysistype = 'SERIAL3D';
        end

        % Button pushed function: Button_LoadIMStack_3D
        function Button_LoadIMStack_3DPushed(app, event)
            try% ask user to open file location
                filterspec = {'*.tiff'};
                [baseName, folder] = uigetfile(filterspec,'MultiSelect', 'on');
                
                % if there are multiple ND2 files then use a different
                % protocol
                if length(baseName)>1
                    fnames = fullfile(folder, baseName);
                    Script_Loadtiff_MF
                else
                    error('need a tiff stack for each time point')
                end
                
                app.AllImages = IMstacks;
                app.ImageFileNames = baseName;
                
            catch
            end
                
            IMs =app.ImageFileNames;
            
            % set the data in the UItable_ImageList to the imagefiles
            % selected
            app.UITable_ImageList_3D.Data = [];
            app.UITable_ImageList_3D.Data = IMs';
            app.UITable_ImageList_ROI_3D.Data = [];
            % set the imagelist in the ROI field as well
            app.UITable_ImageList_ROI_3D.Data = IMs';
            % set the reference image to false for all images, then true
            % for the first 
            app.UITable_ImageList_3D.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));
            app.UITable_ImageList_3D.Data(1,2) = num2cell(true);
            % set the ROI list to have no associated ROI's
            app.UITable_ImageList_ROI_3D.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));
            
            zscale = app.InputmtopixelratioZ_3D.Value;
            
            % set the image in the reference viewer to be the first image
            func_PlaceVol(app.UIAxes_RefVol_3D, app.AllImages{1} ,zscale,'Multi2D');
            % set the image in the deformed viewer to be the second image
            func_PlaceVol(app.UIAxes_DefVol_3D, app.AllImages{2} ,zscale,'Multi2D');
            
            % set the ROI axes to the first image
            IM = imadjust(func_project(app.AllImages{1},'XY'));
            app.ROIMask = ones(size(app.AllImages{1}));
            ROI = func_project(app.ROIMask,'XY');
            ROIfig = imageoverlay_Vol(IM,ROI,0.5);
            func_PlaceFigure(app.UIAxes_ROI_3D,ROIfig);
            
            % initialize an array of ROI masks
            app.AllROIMasks = {};
            for i=1:length(IMs)
                app.AllROIMasks{i}=ones(size(app.AllImages{1}));
            end

        end

        % Selection changed function: ButtonGroup_ROI_3D
        function ButtonGroup_ROI_3DSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_ROI_3D.SelectedObject;
            
            switch selectedButton.Text
                case 'XY projection'
                    % set the ROI axes to the first image
                    IM = func_project(app.AllImages{app.ImgSelected}.*app.ROIMask,'XY');
                    ROI = func_project(app.ROIMask,'XY');
                    ROIfig = imageoverlay_Vol(IM,ROI,0.5);
                    func_PlaceFigure(app.UIAxes_ROI_3D,ROIfig);
                case 'YZ Projection'
                    % set the ROI axes to the first image
                    IM = func_project(app.AllImages{app.ImgSelected}.*app.ROIMask,'YZ');
                    ROI = func_project(app.ROIMask,'YZ');                
                    ROIfig = imageoverlay_Vol(IM,ROI,0.5);
                    func_PlaceFigure(app.UIAxes_ROI_3D,ROIfig);
                case 'XZ projection'
                    % set the ROI axes to the first image
                    IM = func_project(app.AllImages{app.ImgSelected}.*app.ROIMask,'XZ');
                    ROI = func_project(app.ROIMask,'XZ');
                    ROIfig = imageoverlay_Vol(IM,ROI,0.5);
                    func_PlaceFigure(app.UIAxes_ROI_3D,ROIfig);
                case 'Volume'
                    Volume = app.AllImages{app.ImgSelected}.*app.ROIMask;
                    func_PlaceVol(app.UIAxes_ROI_3D,Volume,app.InputmtopixelratioZ_3D.Value,'3D')
            end
        end

        % Button pushed function: Button_rect_pos_3D
        function Button_rect_pos_3DPushed(app, event)
            drawrectangle(app.UIAxes_ROI_3D,'Label','add','Color','w');
        end

        % Button pushed function: Button_rect_neg_3D
        function Button_rect_neg_3DPushed(app, event)
            drawrectangle(app.UIAxes_ROI_3D,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_cir_pos_3D
        function Button_cir_pos_3DPushed(app, event)
            drawcircle(app.UIAxes_ROI_3D,'Label','add','Color','w');
        end

        % Button pushed function: Button_cir_neg_3D
        function Button_cir_neg_3DPushed(app, event)
            drawcircle(app.UIAxes_ROI_3D,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_elip_pos_3D
        function Button_elip_pos_3DPushed(app, event)
            drawellipse(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_elip_neg_3D
        function Button_elip_neg_3DPushed(app, event)
            drawellipse(app.UIAxes_ROI_3D,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_poly_pos_3D
        function Button_poly_pos_3DPushed(app, event)
            drawpolygon(app.UIAxes_ROI_3D,'Label','add','Color','w');
        end

        % Button pushed function: Button_poly_neg_3D
        function Button_poly_neg_3DPushed(app, event)
            drawpolygon(app.UIAxes_ROI_3D,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_clearMask_3D
        function Button_clearMask_3DPushed(app, event)
            % set all images to have ROI
            for i=1:length(app.ROISelected)
                app.AllROIMasks{app.ROISelected(i)} = ones(size(app.AllImages{1}));
                app.UITable_ImageList_ROI_3D.Data(app.ROISelected(i),2) = num2cell(false(1,1));
            end
            
            % set the ROI axes to the first image
            IM = func_project(app.AllImages{1},'XY');
            app.ROIMask = ones(size(app.AllImages{1}));
            ROI = func_project(app.ROIMask,'XY');
            ROIfig = imageoverlay_Vol(IM,ROI,0.5);
            func_PlaceFigure(app.UIAxes_ROI_3D,ROIfig);
            
            app.ButtonGroup_ROI_3D.SelectedObject = app.XYprojectionButton;
            
        end

        % Cell selection callback: UITable_ImageList_ROI_3D
        function UITable_ImageList_ROI_3DCellSelection(app, event)
            indices = event.Indices;
            app.ROISelected = indices;
            
%             if isempty(app.AllROIMasks{app.ROISelected(1)})
%                 background = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
%                 if isempty(app.ROIMask)
%                     foreground = zeros(size(background));
%                 else
%                     foreground = app.ROIMask;
%                 end
%                 
%                 ROIfig = imageoverlay2(background,foreground,0.5);
%                 func_PlaceFigure(app.UIAxes_ROI,ROIfig);
%             else
%                 background = im2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
%                 foreground = app.AllROIMasks{app.ROISelected(1)};
%                 ROIfig = imageoverlay2(background,foreground,0.5);
%                 func_PlaceFigure(app.UIAxes_ROI,ROIfig);
%                 app.ROIMask = foreground;
%             end
            
        end

        % Button pushed function: Button_makeMask_3D
        function Button_makeMask_3DPushed(app, event)
            
            makeVolMaks
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel_DIC = uipanel(app.HomeTab);
            app.Panel_DIC.Visible = 'off';
            app.Button_ALDIC = uibutton(app.Panel_DIC, 'push');
            app.Button_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @Button_ALDICPushed, true);
            app.Button_ALDIC.Text = 'ALDIC';
            app.Button_qDIC = uibutton(app.Panel_DIC, 'push');
            app.Button_qDIC.ButtonPushedFcn = createCallbackFcn(app, @Button_qDICPushed, true);
            app.Button_qDIC.Text = 'STAQ';
            app.Button_FIDIC = uibutton(app.Panel_DIC, 'push');
            app.Button_FIDIC.Enable = 'off';
            app.Button_FIDIC.Visible = 'off';
            app.Button_FIDIC.Text = 'FIDIC';
            app.Panel_TPT = uipanel(app.HomeTab);
            app.Panel_TPT.Visible = 'off';
            app.Button_ST2D = uibutton(app.Panel_TPT, 'push');
            app.Button_ST2D.ButtonPushedFcn = createCallbackFcn(app, @Button_ST2DPushed, true);
            app.Button_ST2D.Text = {'SERIAL-Track'; '2D'};
            app.Button_ST3D = uibutton(app.Panel_TPT, 'push');
            app.Button_ST3D.ButtonPushedFcn = createCallbackFcn(app, @Button_ST3DPushed, true);
            app.Button_ST3D.Text = {'SERIAL-Track '; '3D'};
            app.Button_DICButton = uibutton(app.HomeTab, 'push');
            app.Button_DICButton.ButtonPushedFcn = createCallbackFcn(app, @Button_DICButtonPushed, true);
            app.Button_DICButton.Icon = 'DIC.png';
            app.Button_DICButton.Text = 'DIC';
            app.Button_DVCButton = uibutton(app.HomeTab, 'push');
            app.Button_DVCButton.Icon = 'DVC.png';
            app.Button_DVCButton.Enable = 'off';
            app.Button_DVCButton.Visible = 'off';
            app.Button_DVCButton.Text = {'DVC'; ''};
            app.Button_PT = uibutton(app.HomeTab, 'push');
            app.Button_PT.ButtonPushedFcn = createCallbackFcn(app, @Button_PTPushed, true);
            app.Button_PT.Icon = 'T_PT.png';
            app.Button_PT.Text = 'PT';
            app.Button_DIC3DButton = uibutton(app.HomeTab, 'push');
            app.Button_DIC3DButton.Icon = 'DIC3D.png';
            app.Button_DIC3DButton.Enable = 'off';
            app.Button_DIC3DButton.Visible = 'off';
            app.Button_DIC3DButton.Text = 'DIC 3D';
            app.LoadExperimentDataTab = uitab(app.TabGroup);
            app.LoadExperimentDataTab.Title = 'Load Experiment Data';
            app.TabGroup_Load2D = uitabgroup(app.LoadExperimentDataTab);
            app.LoadImagesTab = uitab(app.TabGroup_Load2D);
            app.LoadImagesTab.Title = 'Load Images';
            app.UIAxes_RefImg = uiaxes(app.LoadImagesTab);
            app.UIAxes_RefImg.XTick = [];
            app.UIAxes_RefImg.YTick = [];
            app.UIAxes_DefImg = uiaxes(app.LoadImagesTab);
            app.UIAxes_DefImg.XTick = [];
            app.UIAxes_DefImg.YTick = [];
            app.DeformedLabel = uilabel(app.LoadImagesTab);
            app.DeformedLabel.Text = 'Deformed';
            app.ReferenceLabel = uilabel(app.LoadImagesTab);
            app.ReferenceLabel.Text = 'Reference';
            app.Button_LoadImages = uibutton(app.LoadImagesTab, 'push');
            app.Button_LoadImages.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadImagesPushed, true);
            app.Button_LoadImages.Text = 'Load Images';
            app.UITable_ImageList = uitable(app.LoadImagesTab);
            app.UITable_ImageList.ColumnName = {'Images'; 'ref'};
            app.UITable_ImageList.RowName = {};
            app.UITable_ImageList.ColumnEditable = [false true];
            app.UITable_ImageList.CellSelectionCallback = createCallbackFcn(app, @UITable_ImageListCellSelection, true);
            app.ImageListLabel_2 = uilabel(app.LoadImagesTab);
            app.ImageListLabel_2.Text = 'ImageList';
            app.InputmtopixelratioEditFieldLabel = uilabel(app.LoadImagesTab);
            app.InputmtopixelratioEditFieldLabel.Text = 'Input μm to pixel ratio';
            app.InputmtopixelratioEditField = uieditfield(app.LoadImagesTab, 'numeric');
            app.InputmtopixelratioEditField.Limits = [0 Inf];
            app.InputmtopixelratioEditField.ValueChangedFcn = createCallbackFcn(app, @InputmtopixelratioEditFieldValueChanged, true);
            app.InputmtopixelratioEditField.Value = 1;
            app.CalibrateratiofromimageButton = uibutton(app.LoadImagesTab, 'push');
            app.CalibrateratiofromimageButton.Enable = 'off';
            app.CalibrateratiofromimageButton.Text = 'Calibrate ratio from image';
            app.ReverseImageSequenceCheckBox = uicheckbox(app.LoadImagesTab);
            app.ReverseImageSequenceCheckBox.Enable = 'off';
            app.ReverseImageSequenceCheckBox.Visible = 'off';
            app.ReverseImageSequenceCheckBox.Text = 'Reverse Image Sequence';
            app.LoadROITab = uitab(app.TabGroup_Load2D);
            app.LoadROITab.Title = 'Load ROI';
            app.UIAxes_ROI = uiaxes(app.LoadROITab);
            app.UIAxes_ROI.XTick = [];
            app.UIAxes_ROI.YTick = [];
            app.ImageListLabel = uilabel(app.LoadROITab);
            app.ImageListLabel.Text = 'ImageList';
            app.Button_LoadROI_set = uibutton(app.LoadROITab, 'push');
            app.Button_LoadROI_set.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadROI_setPushed, true);
            app.Button_LoadROI_set.Text = 'Load ROI set';
            app.UITable_ImageList_ROI = uitable(app.LoadROITab);
            app.UITable_ImageList_ROI.ColumnName = {'Images'; 'Has ROI'};
            app.UITable_ImageList_ROI.RowName = {};
            app.UITable_ImageList_ROI.ColumnEditable = [false true];
            app.UITable_ImageList_ROI.CellSelectionCallback = createCallbackFcn(app, @UITable_ImageList_ROICellSelection, true);
            app.Button_LoadROI_single = uibutton(app.LoadROITab, 'push');
            app.Button_LoadROI_single.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadROI_singlePushed, true);
            app.Button_LoadROI_single.Text = 'Load ROI for selection';
            app.ModifyROIPanel = uipanel(app.LoadROITab);
            app.ModifyROIPanel.Title = 'Modify ROI';
            app.Button_rect_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_rect_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_rect_posPushed, true);
            app.Button_rect_pos.Text = 'Rectangle (+)';
            app.AddmaskLabel = uilabel(app.ModifyROIPanel);
            app.AddmaskLabel.Text = 'Add + mask';
            app.Button_cir_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_cir_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_cir_posPushed, true);
            app.Button_cir_pos.Text = 'Circle (+)';
            app.SubtractmaskLabel = uilabel(app.ModifyROIPanel);
            app.SubtractmaskLabel.Text = 'Subtract (- mask)';
            app.Button_rect_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_rect_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_rect_negPushed, true);
            app.Button_rect_neg.Text = 'Rectangle (-)';
            app.Button_cir_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_cir_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_cir_negPushed, true);
            app.Button_cir_neg.Text = 'Circle (-)';
            app.Button_poly_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_poly_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_poly_posPushed, true);
            app.Button_poly_pos.Text = 'Poly Line (+)';
            app.Button_elip_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_elip_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_elip_posPushed, true);
            app.Button_elip_pos.Text = 'Ellipse (+)';
            app.Button_poly_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_poly_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_poly_negPushed, true);
            app.Button_poly_neg.Text = 'Poly Line (-)';
            app.Button_elip_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_elip_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_elip_negPushed, true);
            app.Button_elip_neg.Text = 'Ellipse (-)';
            app.Button_makeMask = uibutton(app.ModifyROIPanel, 'push');
            app.Button_makeMask.ButtonPushedFcn = createCallbackFcn(app, @Button_makeMaskPushed, true);
            app.Button_makeMask.Text = 'Make Mask';
            app.Button_clearMask = uibutton(app.ModifyROIPanel, 'push');
            app.Button_clearMask.ButtonPushedFcn = createCallbackFcn(app, @Button_clearMaskPushed, true);
            app.Button_clearMask.Text = 'Clear mask';
            app.AutoROIPanel = uipanel(app.LoadROITab);
            app.AutoROIPanel.Title = 'Auto ROI';
            app.TabGroup3 = uitabgroup(app.AutoROIPanel);
            app.FilterbasedTab = uitab(app.TabGroup3);
            app.FilterbasedTab.Title = 'Filter based';
            app.Button_SelectDICpattern_AutoROI = uibutton(app.FilterbasedTab, 'push');
            app.Button_SelectDICpattern_AutoROI.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectDICpattern_AutoROIPushed, true);
            app.Button_SelectDICpattern_AutoROI.Text = 'Auto Filter';
            app.lowEditFieldLabel = uilabel(app.FilterbasedTab);
            app.lowEditFieldLabel.Text = 'low';
            app.lowEditField = uieditfield(app.FilterbasedTab, 'numeric');
            app.lowEditField.Limits = [0 1];
            app.highEditFieldLabel = uilabel(app.FilterbasedTab);
            app.highEditFieldLabel.Text = 'high';
            app.highEditField = uieditfield(app.FilterbasedTab, 'numeric');
            app.highEditField.Limits = [0 1];
            app.highEditField.Value = 1;
            app.filterboundsLabel = uilabel(app.FilterbasedTab);
            app.filterboundsLabel.Text = 'filter bounds';
            app.orderEditFieldLabel = uilabel(app.FilterbasedTab);
            app.orderEditFieldLabel.Text = 'order';
            app.orderEditField = uieditfield(app.FilterbasedTab, 'numeric');
            app.orderEditField.Limits = [1 10];
            app.orderEditField.Value = 3;
            app.graphsegmenterTab = uitab(app.TabGroup3);
            app.graphsegmenterTab.Title = 'graph segmenter';
            app.selectforegroundButton = uibutton(app.graphsegmenterTab, 'push');
            app.selectforegroundButton.ButtonPushedFcn = createCallbackFcn(app, @selectforegroundButtonPushed, true);
            app.selectforegroundButton.Text = 'select foreground';
            app.selectbackgroundButton = uibutton(app.graphsegmenterTab, 'push');
            app.selectbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @selectbackgroundButtonPushed, true);
            app.selectbackgroundButton.Text = 'select background';
            app.GenerateROIButton = uibutton(app.graphsegmenterTab, 'push');
            app.GenerateROIButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateROIButtonPushed, true);
            app.GenerateROIButton.Text = 'Generate ROI';
            app.ApplyROIPanel = uipanel(app.LoadROITab);
            app.ApplyROIPanel.Title = 'Apply ROI';
            app.Button_ClearROI = uibutton(app.ApplyROIPanel, 'push');
            app.Button_ClearROI.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearROIPushed, true);
            app.Button_ClearROI.Text = 'Clear selected';
            app.Button_Applytoselected = uibutton(app.ApplyROIPanel, 'push');
            app.Button_Applytoselected.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplytoselectedPushed, true);
            app.Button_Applytoselected.Text = 'Apply to selected';
            app.Button_Applytoall = uibutton(app.ApplyROIPanel, 'push');
            app.Button_Applytoall.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplytoallPushed, true);
            app.Button_Applytoall.Text = 'Apply to all';
            app.Button_ClearAll = uibutton(app.ApplyROIPanel, 'push');
            app.Button_ClearAll.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearAllPushed, true);
            app.Button_ClearAll.Text = 'Clear all';
            app.TabGroup_Load3D = uitabgroup(app.LoadExperimentDataTab);
            app.LoadImagesTab_3D = uitab(app.TabGroup_Load3D);
            app.LoadImagesTab_3D.Title = 'Load Images';
            app.UIAxes_RefVol_3D = uiaxes(app.LoadImagesTab_3D);
            app.UIAxes_RefVol_3D.XTick = [];
            app.UIAxes_RefVol_3D.YTick = [];
            app.UIAxes_DefVol_3D = uiaxes(app.LoadImagesTab_3D);
            app.UIAxes_DefVol_3D.XTick = [];
            app.UIAxes_DefVol_3D.YTick = [];
            app.DeformedLabel_3D = uilabel(app.LoadImagesTab_3D);
            app.DeformedLabel_3D.Text = 'Deformed';
            app.ReferenceLabel_3D = uilabel(app.LoadImagesTab_3D);
            app.ReferenceLabel_3D.Text = 'Reference';
            app.Button_LoadND2 = uibutton(app.LoadImagesTab_3D, 'push');
            app.Button_LoadND2.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadND2Pushed, true);
            app.Button_LoadND2.Text = 'Load ND2';
            app.ImageListLabel_3D = uilabel(app.LoadImagesTab_3D);
            app.ImageListLabel_3D.Text = 'ImageList';
            app.InputmtopixelratioinXYplaneLabel = uilabel(app.LoadImagesTab_3D);
            app.InputmtopixelratioinXYplaneLabel.Text = 'Input μm to pixel ratio in XY plane';
            app.InputmtopixelratioXY_3D = uieditfield(app.LoadImagesTab_3D, 'numeric');
            app.InputmtopixelratioXY_3D.Limits = [0 Inf];
            app.InputmtopixelratioXY_3D.Value = 1;
            app.Button_LoadIMStack_3D = uibutton(app.LoadImagesTab_3D, 'push');
            app.Button_LoadIMStack_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadIMStack_3DPushed, true);
            app.Button_LoadIMStack_3D.Text = 'Load Image Stack';
            app.UITable_ImageList_3D = uitable(app.LoadImagesTab_3D);
            app.UITable_ImageList_3D.ColumnName = {'Images'; 'ref'};
            app.UITable_ImageList_3D.RowName = {};
            app.UITable_ImageList_3D.ColumnEditable = [false true];
            app.InputmtopixelratioinZstepsizeLabel = uilabel(app.LoadImagesTab_3D);
            app.InputmtopixelratioinZstepsizeLabel.Text = 'Input μm to pixel ratio in Z (step size)';
            app.InputmtopixelratioZ_3D = uieditfield(app.LoadImagesTab_3D, 'numeric');
            app.InputmtopixelratioZ_3D.Limits = [0 Inf];
            app.InputmtopixelratioZ_3D.Value = 16;
            app.LoadROITab_3D = uitab(app.TabGroup_Load3D);
            app.LoadROITab_3D.Title = 'Load ROI';
            app.UIAxes_ROI_3D = uiaxes(app.LoadROITab_3D);
            app.UIAxes_ROI_3D.XTick = [];
            app.UIAxes_ROI_3D.YTick = [];
            app.ImageListLabel_3D_ROI = uilabel(app.LoadROITab_3D);
            app.ImageListLabel_3D_ROI.Text = 'ImageList';
            app.Button_LoadROI_set_3D = uibutton(app.LoadROITab_3D, 'push');
            app.Button_LoadROI_set_3D.Enable = 'off';
            app.Button_LoadROI_set_3D.Text = 'Load ROI set';
            app.UITable_ImageList_ROI_3D = uitable(app.LoadROITab_3D);
            app.UITable_ImageList_ROI_3D.ColumnName = {'Images'; 'Has ROI'};
            app.UITable_ImageList_ROI_3D.RowName = {};
            app.UITable_ImageList_ROI_3D.ColumnEditable = [false true];
            app.UITable_ImageList_ROI_3D.CellSelectionCallback = createCallbackFcn(app, @UITable_ImageList_ROI_3DCellSelection, true);
            app.Button_LoadROI_single_3D = uibutton(app.LoadROITab_3D, 'push');
            app.Button_LoadROI_single_3D.Enable = 'off';
            app.Button_LoadROI_single_3D.Text = 'Load ROI for selection';
            app.ModifyROIPanel_3D = uipanel(app.LoadROITab_3D);
            app.ModifyROIPanel_3D.Title = 'Modify ROI';
            app.Button_rect_pos_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_rect_pos_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_rect_pos_3DPushed, true);
            app.Button_rect_pos_3D.Text = 'Rectangle (+)';
            app.AddmaskLabel_3D = uilabel(app.ModifyROIPanel_3D);
            app.AddmaskLabel_3D.Text = 'Add + mask';
            app.Button_cir_pos_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_cir_pos_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_cir_pos_3DPushed, true);
            app.Button_cir_pos_3D.Text = 'Circle (+)';
            app.SubtractmaskLabel_3D = uilabel(app.ModifyROIPanel_3D);
            app.SubtractmaskLabel_3D.Text = 'Subtract (- mask)';
            app.Button_rect_neg_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_rect_neg_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_rect_neg_3DPushed, true);
            app.Button_rect_neg_3D.Text = 'Rectangle (-)';
            app.Button_cir_neg_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_cir_neg_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_cir_neg_3DPushed, true);
            app.Button_cir_neg_3D.Text = 'Circle (-)';
            app.Button_poly_pos_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_poly_pos_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_poly_pos_3DPushed, true);
            app.Button_poly_pos_3D.Text = 'Poly Line (+)';
            app.Button_elip_pos_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_elip_pos_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_elip_pos_3DPushed, true);
            app.Button_elip_pos_3D.Text = 'Ellipse (+)';
            app.Button_poly_neg_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_poly_neg_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_poly_neg_3DPushed, true);
            app.Button_poly_neg_3D.Text = 'Poly Line (-)';
            app.Button_elip_neg_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_elip_neg_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_elip_neg_3DPushed, true);
            app.Button_elip_neg_3D.Text = 'Ellipse (-)';
            app.Button_makeMask_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_makeMask_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_makeMask_3DPushed, true);
            app.Button_makeMask_3D.Text = 'Make Mask';
            app.Button_clearMask_3D = uibutton(app.ModifyROIPanel_3D, 'push');
            app.Button_clearMask_3D.ButtonPushedFcn = createCallbackFcn(app, @Button_clearMask_3DPushed, true);
            app.Button_clearMask_3D.Text = 'Clear mask';
            app.ApplyROIPanel_3 = uipanel(app.LoadROITab_3D);
            app.ApplyROIPanel_3.Title = 'Apply ROI';
            app.Button_ClearROI_3D = uibutton(app.ApplyROIPanel_3, 'push');
            app.Button_ClearROI_3D.Enable = 'off';
            app.Button_ClearROI_3D.Text = 'Clear selected';
            app.Button_Applytoselected_3D = uibutton(app.ApplyROIPanel_3, 'push');
            app.Button_Applytoselected_3D.Enable = 'off';
            app.Button_Applytoselected_3D.Text = 'Apply to selected';
            app.Button_Applytoall_3D = uibutton(app.ApplyROIPanel_3, 'push');
            app.Button_Applytoall_3D.Enable = 'off';
            app.Button_Applytoall_3D.Text = 'Apply to all';
            app.Button_ClearAll_3D = uibutton(app.ApplyROIPanel_3, 'push');
            app.Button_ClearAll_3D.Enable = 'off';
            app.Button_ClearAll_3D.Text = 'Clear all';
            app.ButtonGroup_ROI_3D = uibuttongroup(app.LoadROITab_3D);
            app.ButtonGroup_ROI_3D.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_ROI_3DSelectionChanged, true);
            app.ButtonGroup_ROI_3D.Title = 'Button Group';
            app.XYprojectionButton = uitogglebutton(app.ButtonGroup_ROI_3D);
            app.XYprojectionButton.Text = 'XY projection';
            app.XYprojectionButton.Value = true;
            app.YZProjectionButton = uitogglebutton(app.ButtonGroup_ROI_3D);
            app.YZProjectionButton.Text = 'YZ Projection';
            app.XZprojectionButton = uitogglebutton(app.ButtonGroup_ROI_3D);
            app.XZprojectionButton.Text = 'XZ projection';
            app.Image_XYproj = uiimage(app.ButtonGroup_ROI_3D);
            app.Image_XYproj.ImageSource = 'projections-01.png';
            app.Image_YZproj = uiimage(app.ButtonGroup_ROI_3D);
            app.Image_YZproj.ImageSource = 'projections-03.png';
            app.Image_XZproj = uiimage(app.ButtonGroup_ROI_3D);
            app.Image_XZproj.ImageSource = 'projections-02.png';
            app.VolumeButton = uitogglebutton(app.ButtonGroup_ROI_3D);
            app.VolumeButton.Text = 'Volume';
            app.Image_XZproj_2 = uiimage(app.ButtonGroup_ROI_3D);
            app.Image_XZproj_2.ImageSource = 'projections-04.png';
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.AnalysisTabGroup_ALDIC = uitabgroup(app.StartTab);
            app.AnalysislistTab_ALDIC = uitab(app.AnalysisTabGroup_ALDIC);
            app.AnalysislistTab_ALDIC.Title = 'Start Analysis';
            app.AnalysisnameEditFieldLabel = uilabel(app.AnalysislistTab_ALDIC);
            app.AnalysisnameEditFieldLabel.Text = 'Analysis name';
            app.AnalysisnameEditField_ALDIC = uieditfield(app.AnalysislistTab_ALDIC, 'text');
            app.AnalysisnameEditField_ALDIC.ValueChangedFcn = createCallbackFcn(app, @AnalysisnameEditField_ALDICValueChanged, true);
            app.AnalysisnameEditField_ALDIC.ValueChangingFcn = createCallbackFcn(app, @AnalysisnameEditField_ALDICValueChanging, true);
            app.StartnewanalysisButton_ALDIC = uibutton(app.AnalysislistTab_ALDIC, 'push');
            app.StartnewanalysisButton_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @StartnewanalysisButton_ALDICPushed, true);
            app.StartnewanalysisButton_ALDIC.Enable = 'off';
            app.StartnewanalysisButton_ALDIC.Text = 'Start new analysis';
            app.AnalysistypeALDICLabel = uilabel(app.AnalysislistTab_ALDIC);
            app.AnalysistypeALDICLabel.Text = 'Analysis type: ALDIC';
            app.SetinitialdisplacementguessTab_ALDIC = uitab(app.AnalysisTabGroup_ALDIC);
            app.SetinitialdisplacementguessTab_ALDIC.Title = 'Initial Guess';
            app.RadioGroup_InitialguessMethod_ALDIC = uibuttongroup(app.SetinitialdisplacementguessTab_ALDIC);
            app.RadioGroup_InitialguessMethod_ALDIC.SelectionChangedFcn = createCallbackFcn(app, @RadioGroup_InitialguessMethod_ALDICSelectionChanged, true);
            app.RadioGroup_InitialguessMethod_ALDIC.Title = 'Method';
            app.Radio_Multigridsearch_ALDIC = uiradiobutton(app.RadioGroup_InitialguessMethod_ALDIC);
            app.Radio_Multigridsearch_ALDIC.Text = 'Multigrid search';
            app.Radio_Multigridsearch_ALDIC.Value = true;
            app.Radio_Wholefieldsearch_ALDIC = uiradiobutton(app.RadioGroup_InitialguessMethod_ALDIC);
            app.Radio_Wholefieldsearch_ALDIC.Text = 'Whole field search';
            app.Radio_Manualseed_ALDIC = uiradiobutton(app.RadioGroup_InitialguessMethod_ALDIC);
            app.Radio_Manualseed_ALDIC.Enable = 'off';
            app.Radio_Manualseed_ALDIC.Text = 'Manual seed';
            app.StartsearchButton_ALDIC = uibutton(app.SetinitialdisplacementguessTab_ALDIC, 'push');
            app.StartsearchButton_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @StartsearchButton_ALDICPushed, true);
            app.StartsearchButton_ALDIC.Text = 'Start search';
            app.SearchsettingsPanel_ALDIC = uipanel(app.SetinitialdisplacementguessTab_ALDIC);
            app.SearchsettingsPanel_ALDIC.Title = 'Search settings';
            app.searchregionsizeEditFieldLabel = uilabel(app.SearchsettingsPanel_ALDIC);
            app.searchregionsizeEditFieldLabel.Text = 'search region size';
            app.searchregionsizeEditField_ALDIC = uieditfield(app.SearchsettingsPanel_ALDIC, 'numeric');
            app.searchregionsizeEditField_ALDIC.Limits = [1 Inf];
            app.searchregionsizeEditField_ALDIC.RoundFractionalValues = 'on';
            app.searchregionsizeEditField_ALDIC.ValueChangedFcn = createCallbackFcn(app, @searchregionsizeEditField_ALDICValueChanged, true);
            app.searchregionsizeEditField_ALDIC.Tooltip = {'Should be larger than the maximum displacement'};
            app.searchregionsizeEditField_ALDIC.Value = 50;
            app.searchstepsizeEditFieldLabel = uilabel(app.SearchsettingsPanel_ALDIC);
            app.searchstepsizeEditFieldLabel.Text = 'search step size';
            app.searchstepsizeEditField_ALDIC = uieditfield(app.SearchsettingsPanel_ALDIC, 'numeric');
            app.searchstepsizeEditField_ALDIC.Limits = [1 Inf];
            app.searchstepsizeEditField_ALDIC.RoundFractionalValues = 'on';
            app.searchstepsizeEditField_ALDIC.ValueChangedFcn = createCallbackFcn(app, @searchstepsizeEditField_ALDICValueChanged, true);
            app.searchstepsizeEditField_ALDIC.Tooltip = {'sets the number of grid points '};
            app.searchstepsizeEditField_ALDIC.Value = 20;
            app.maxdisplacementEditFieldLabel = uilabel(app.SetinitialdisplacementguessTab_ALDIC);
            app.maxdisplacementEditFieldLabel.Text = 'max displacement';
            app.maxdisplacementEditField_ALDIC = uieditfield(app.SetinitialdisplacementguessTab_ALDIC, 'numeric');
            app.maxdisplacementEditField_ALDIC.Editable = 'off';
            app.maxdisplacementEditField_ALDIC.Tooltip = {'Will update upon each iteration'};
            app.Panel_2 = uipanel(app.SetinitialdisplacementguessTab_ALDIC);
            app.IDG_ReferenceImage_ALDIC = uiaxes(app.Panel_2);
            title(app.IDG_ReferenceImage_ALDIC, 'Reference ')
            app.IDG_ReferenceImage_ALDIC.XTick = [];
            app.IDG_ReferenceImage_ALDIC.YTick = [];
            app.IDG_DeformedImage_ALDIC = uiaxes(app.Panel_2);
            title(app.IDG_DeformedImage_ALDIC, 'Deformed')
            app.IDG_DeformedImage_ALDIC.XTick = [];
            app.IDG_DeformedImage_ALDIC.YTick = [];
            app.SetDICparametersTab_ALDIC = uitab(app.AnalysisTabGroup_ALDIC);
            app.SetDICparametersTab_ALDIC.Title = 'DIC';
            app.ALDICOptionsPanel_ALDIC = uipanel(app.SetDICparametersTab_ALDIC);
            app.ALDICOptionsPanel_ALDIC.Title = 'ALDIC Options';
            app.numberofparallelpoolsSpinnerLabel = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.numberofparallelpoolsSpinnerLabel.Text = {'number of'; ' parallel pools'};
            app.numberofparallelpoolsSpinner_ALDIC = uispinner(app.ALDICOptionsPanel_ALDIC);
            app.numberofparallelpoolsSpinner_ALDIC.Limits = [0 Inf];
            app.Subproblem2MethodButtonGroup_ALDIC = uibuttongroup(app.ALDICOptionsPanel_ALDIC);
            app.Subproblem2MethodButtonGroup_ALDIC.Title = 'Subproblem 2 Method';
            app.FiniteDifferenceButton_ALDIC = uiradiobutton(app.Subproblem2MethodButtonGroup_ALDIC);
            app.FiniteDifferenceButton_ALDIC.Text = 'Finite Difference';
            app.FiniteDifferenceButton_ALDIC.Value = true;
            app.FiniteElementMethodButton_ALDIC = uiradiobutton(app.Subproblem2MethodButtonGroup_ALDIC);
            app.FiniteElementMethodButton_ALDIC.Text = 'Finite Element Method';
            app.Subproblem1MethodButtonGroup_ALDIC = uibuttongroup(app.ALDICOptionsPanel_ALDIC);
            app.Subproblem1MethodButtonGroup_ALDIC.Title = 'Subproblem 1 Method';
            app.LevenbergMarquardtButton_ALDIC = uiradiobutton(app.Subproblem1MethodButtonGroup_ALDIC);
            app.LevenbergMarquardtButton_ALDIC.Text = 'Levenberg Marquardt';
            app.LevenbergMarquardtButton_ALDIC.Value = true;
            app.GaussNewtonButton_ALDIC = uiradiobutton(app.Subproblem1MethodButtonGroup_ALDIC);
            app.GaussNewtonButton_ALDIC.Enable = 'off';
            app.GaussNewtonButton_ALDIC.Text = 'Gauss Newton';
            app.windowsizeEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.windowsizeEditFieldLabel_2.Text = 'window size';
            app.windowsizeEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.windowsizeEditField_ALDIC.Limits = [1 Inf];
            app.windowsizeEditField_ALDIC.Value = 50;
            app.stepsizeEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.stepsizeEditFieldLabel_2.Text = 'step size';
            app.stepsizeEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.stepsizeEditField_ALDIC.Limits = [1 Inf];
            app.stepsizeEditField_ALDIC.Value = 20;
            app.toleranceEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.toleranceEditFieldLabel_2.Text = 'tolerance';
            app.toleranceEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.toleranceEditField_ALDIC.Limits = [1e-15 Inf];
            app.toleranceEditField_ALDIC.Value = 0.01;
            app.muEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.muEditFieldLabel_2.Text = 'mu';
            app.muEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.muEditField_ALDIC.Limits = [1e-15 Inf];
            app.muEditField_ALDIC.Value = 0.001;
            app.InitialguessoptionsPanel_ALDIC = uipanel(app.SetDICparametersTab_ALDIC);
            app.InitialguessoptionsPanel_ALDIC.Title = 'Initial guess options';
            app.UsePODCheckBox_ALDIC = uicheckbox(app.InitialguessoptionsPanel_ALDIC);
            app.UsePODCheckBox_ALDIC.Enable = 'off';
            app.UsePODCheckBox_ALDIC.Text = 'Use POD';
            app.UsePODCheckBox_ALDIC.Value = true;
            app.UsemostrecentframeasguessCheckBox_ALDIC = uicheckbox(app.InitialguessoptionsPanel_ALDIC);
            app.UsemostrecentframeasguessCheckBox_ALDIC.Text = 'Use most recent frame as guess';
            app.UsemostrecentframeasguessCheckBox_ALDIC.Value = true;
            app.StartAnalysis_ALDIC = uibutton(app.SetDICparametersTab_ALDIC, 'push');
            app.StartAnalysis_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysis_ALDICPushed, true);
            app.StartAnalysis_ALDIC.Text = 'Start Analysis';
            app.Panel_4 = uipanel(app.SetDICparametersTab_ALDIC);
            app.A_Udisp_ALDIC = uiaxes(app.Panel_4);
            title(app.A_Udisp_ALDIC, 'U-Displacement')
            app.A_Udisp_ALDIC.XTick = [];
            app.A_Udisp_ALDIC.YTick = [];
            app.A_Vdisp_ALDIC = uiaxes(app.Panel_4);
            title(app.A_Vdisp_ALDIC, 'V-Displacement')
            app.A_Vdisp_ALDIC.XTick = [];
            app.A_Vdisp_ALDIC.YTick = [];
            app.AnalysisTabGroup_SERIAL = uitabgroup(app.StartTab);
            app.AnalysislistTab_SERIAL = uitab(app.AnalysisTabGroup_SERIAL);
            app.AnalysislistTab_SERIAL.Title = 'Start Analysis';
            app.AnalysisnameEditFieldLabel_2 = uilabel(app.AnalysislistTab_SERIAL);
            app.AnalysisnameEditFieldLabel_2.Text = 'Analysis name';
            app.AnalysisnameEditField_SERIAL = uieditfield(app.AnalysislistTab_SERIAL, 'text');
            app.AnalysisnameEditField_SERIAL.ValueChangedFcn = createCallbackFcn(app, @AnalysisnameEditField_SERIALValueChanged, true);
            app.StartnewanalysisButton_SERIAL = uibutton(app.AnalysislistTab_SERIAL, 'push');
            app.StartnewanalysisButton_SERIAL.ButtonPushedFcn = createCallbackFcn(app, @StartnewanalysisButton_SERIALPushed, true);
            app.StartnewanalysisButton_SERIAL.Enable = 'off';
            app.StartnewanalysisButton_SERIAL.Text = 'Start new analysis';
            app.AnalysistypeSERIALTrackLabel = uilabel(app.AnalysislistTab_SERIAL);
            app.AnalysistypeSERIALTrackLabel.Text = 'Analysis type: SERIAL-Track';
            app.PtpTab_SERIAL = uitab(app.AnalysisTabGroup_SERIAL);
            app.PtpTab_SERIAL.Title = 'Particle tracking parameters';
            app.StartsearchButton_SERIAL = uibutton(app.PtpTab_SERIAL, 'push');
            app.StartsearchButton_SERIAL.Enable = 'off';
            app.StartsearchButton_SERIAL.Visible = 'off';
            app.StartsearchButton_SERIAL.Text = 'Start search';
            app.Panel_5 = uipanel(app.PtpTab_SERIAL);
            app.Panel_5.Enable = 'off';
            app.Panel_5.Visible = 'off';
            app.IDG_ReferenceImage_SERIAL = uiaxes(app.Panel_5);
            title(app.IDG_ReferenceImage_SERIAL, 'Reference ')
            app.IDG_ReferenceImage_SERIAL.XTick = [];
            app.IDG_ReferenceImage_SERIAL.YTick = [];
            app.IDG_TrackedImage_SERIAL = uiaxes(app.Panel_5);
            title(app.IDG_TrackedImage_SERIAL, 'Deformed')
            app.IDG_TrackedImage_SERIAL.XTick = [];
            app.IDG_TrackedImage_SERIAL.YTick = [];
            app.TabGroup4_2 = uitabgroup(app.PtpTab_SERIAL);
            app.TabGroup4_2.Visible = 'off';
            app.BeadParamsTab_2 = uitab(app.TabGroup4_2);
            app.BeadParamsTab_2.Title = 'Bead Params';
            app.beadthresholdEditField_2Label = uilabel(app.BeadParamsTab_2);
            app.beadthresholdEditField_2Label.Text = 'bead threshold';
            app.beadthresholdEditField_2_SERIAL = uieditfield(app.BeadParamsTab_2, 'numeric');
            app.beadthresholdEditField_2_SERIAL.Limits = [0 1];
            app.beadthresholdEditField_2_SERIAL.Value = 0.5;
            app.avgbeadsizeEditField_2Label = uilabel(app.BeadParamsTab_2);
            app.avgbeadsizeEditField_2Label.Text = 'avg bead size';
            app.avgbeadsizeEditField_2_SERIAL = uieditfield(app.BeadParamsTab_2, 'numeric');
            app.avgbeadsizeEditField_2_SERIAL.Limits = [1 Inf];
            app.avgbeadsizeEditField_2_SERIAL.Value = 3;
            app.minbeadsizeEditField_2Label = uilabel(app.BeadParamsTab_2);
            app.minbeadsizeEditField_2Label.Text = 'min bead size';
            app.minbeadsizeEditField_2_SERIAL = uieditfield(app.BeadParamsTab_2, 'numeric');
            app.minbeadsizeEditField_2_SERIAL.Limits = [1 Inf];
            app.minbeadsizeEditField_2_SERIAL.Value = 2;
            app.maxbeadsizeEditField_4Label = uilabel(app.BeadParamsTab_2);
            app.maxbeadsizeEditField_4Label.Text = 'max bead size';
            app.maxbeadsizeEditField_4_SERIAL = uieditfield(app.BeadParamsTab_2, 'numeric');
            app.maxbeadsizeEditField_4_SERIAL.Limits = [1 Inf];
            app.maxbeadsizeEditField_4_SERIAL.Value = 20;
            app.maxwinsizeEditFieldLabel = uilabel(app.BeadParamsTab_2);
            app.maxwinsizeEditFieldLabel.Text = 'max win size';
            app.maxwinsizeEditField_4_SERIAL = uieditfield(app.BeadParamsTab_2, 'numeric');
            app.maxwinsizeEditField_4_SERIAL.Limits = [1 Inf];
            app.maxwinsizeEditField_4_SERIAL.Value = 5;
            app.distMissingEditField_2Label = uilabel(app.BeadParamsTab_2);
            app.distMissingEditField_2Label.Text = 'dist Missing';
            app.distMissingEditField_2_SERIAL = uieditfield(app.BeadParamsTab_2, 'numeric');
            app.distMissingEditField_2_SERIAL.Limits = [1 Inf];
            app.distMissingEditField_2_SERIAL.Value = 2;
            app.BeadColorDropDown_2Label = uilabel(app.BeadParamsTab_2);
            app.BeadColorDropDown_2Label.Text = 'Bead Color';
            app.BeadColorDropDown_2_SERIAL = uidropdown(app.BeadParamsTab_2);
            app.BeadColorDropDown_2_SERIAL.Items = {'Dark', 'Light', ''};
            app.BeadColorDropDown_2_SERIAL.Value = 'Light';
            app.PSFtypeDropDown_2Label = uilabel(app.BeadParamsTab_2);
            app.PSFtypeDropDown_2Label.Text = 'PSF type';
            app.PSFtypeDropDown_2_SERIAL = uidropdown(app.BeadParamsTab_2);
            app.PSFtypeDropDown_2_SERIAL.Items = {'None', 'fspecial'};
            app.PSFtypeDropDown_2_SERIAL.Value = 'None';
            app.TrackOptsTab_2_SERIAL = uitab(app.TabGroup4_2);
            app.TrackOptsTab_2_SERIAL.Title = 'Track Opts';
            app.DetectionmethodDropDown_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.DetectionmethodDropDown_2Label.Text = 'Detection method';
            app.DetectionmethodDropDown_2_SERIAL = uidropdown(app.TrackOptsTab_2_SERIAL);
            app.DetectionmethodDropDown_2_SERIAL.Items = {'LoG', 'TPT', 'sift', ''};
            app.DetectionmethodDropDown_2_SERIAL.Value = 'LoG';
            app.ParticleTypeDropDown_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.ParticleTypeDropDown_2Label.Text = 'Particle Type';
            app.ParticleTypeDropDown_2_SERIAL = uidropdown(app.TrackOptsTab_2_SERIAL);
            app.ParticleTypeDropDown_2_SERIAL.Items = {'Hard', 'Soft', '', ''};
            app.ParticleTypeDropDown_2_SERIAL.Value = 'Hard';
            app.TrackingModeDropDown_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.TrackingModeDropDown_2Label.Text = 'Tracking Mode';
            app.TrackingModeDropDown_2_SERIAL = uidropdown(app.TrackOptsTab_2_SERIAL);
            app.TrackingModeDropDown_2_SERIAL.Items = {'Cumulative', 'Incremental', '', '', ''};
            app.TrackingModeDropDown_2_SERIAL.Value = 'Cumulative';
            app.SearchFieldEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.SearchFieldEditField_2Label.Text = 'Search Field';
            app.SearchFieldEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.SearchFieldEditField_2_SERIAL.Limits = [1 Inf];
            app.SearchFieldEditField_2_SERIAL.Value = Inf;
            app.MaxNeighborsEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.MaxNeighborsEditField_2Label.Text = 'Max Neighbors';
            app.MaxNeighborsEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.MaxNeighborsEditField_2_SERIAL.Limits = [1 Inf];
            app.MaxNeighborsEditField_2_SERIAL.Value = 25;
            app.MinNeighborsEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.MinNeighborsEditField_2Label.Text = 'Min Neighbors';
            app.MinNeighborsEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.MinNeighborsEditField_2_SERIAL.Limits = [1 Inf];
            app.MinNeighborsEditField_2_SERIAL.Value = 1;
            app.GlobalstepsolverDropDown_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.GlobalstepsolverDropDown_2Label.Text = 'Global step solver';
            app.GlobalstepsolverDropDown_2_SERIAL = uidropdown(app.TrackOptsTab_2_SERIAL);
            app.GlobalstepsolverDropDown_2_SERIAL.Items = {'Moving lease square ', 'Global regularization', 'ADMM iterations'};
            app.GlobalstepsolverDropDown_2_SERIAL.Value = 'ADMM iterations';
            app.regularizationcoefEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.regularizationcoefEditField_2Label.Text = 'regularization coef.';
            app.regularizationcoefEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.regularizationcoefEditField_2_SERIAL.Limits = [1e-09 1];
            app.regularizationcoefEditField_2_SERIAL.Value = 0.01;
            app.outlierthreshEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.outlierthreshEditField_2Label.Text = 'outlier thresh';
            app.outlierthreshEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.outlierthreshEditField_2_SERIAL.Limits = [1 Inf];
            app.outlierthreshEditField_2_SERIAL.Value = 2;
            app.MaxADMMiterEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.MaxADMMiterEditField_2Label.Text = 'Max ADMM iter';
            app.MaxADMMiterEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.MaxADMMiterEditField_2_SERIAL.Limits = [1 Inf];
            app.MaxADMMiterEditField_2_SERIAL.Value = 20;
            app.ADMMthreshEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.ADMMthreshEditField_2Label.Text = 'ADMM thresh';
            app.ADMMthreshEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.ADMMthreshEditField_2_SERIAL.Limits = [1e-09 1];
            app.ADMMthreshEditField_2_SERIAL.Value = 0.01;
            app.MaxstrainNeighborsEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.MaxstrainNeighborsEditField_2Label.Text = 'Max strain Neighbors';
            app.MaxstrainNeighborsEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.MaxstrainNeighborsEditField_2_SERIAL.Limits = [1 Inf];
            app.MaxstrainNeighborsEditField_2_SERIAL.Value = 20;
            app.xstepEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.xstepEditField_2Label.Text = 'x step';
            app.xstepEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.xstepEditField_2_SERIAL.Limits = [1 Inf];
            app.xstepEditField_2_SERIAL.Value = 1;
            app.tstepEditField_2Label = uilabel(app.TrackOptsTab_2_SERIAL);
            app.tstepEditField_2Label.Text = 't step';
            app.tstepEditField_2_SERIAL = uieditfield(app.TrackOptsTab_2_SERIAL, 'numeric');
            app.tstepEditField_2_SERIAL.Limits = [1 Inf];
            app.tstepEditField_2_SERIAL.Value = 1;
            app.SetparametersTab_SERIAL = uitab(app.AnalysisTabGroup_SERIAL);
            app.SetparametersTab_SERIAL.Title = 'Track Particles';
            app.StartAnalysis_SERIAL = uibutton(app.SetparametersTab_SERIAL, 'push');
            app.StartAnalysis_SERIAL.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysis_SERIALButtonPushed, true);
            app.StartAnalysis_SERIAL.Text = 'Start Analysis';
            app.PlotResults_SERIAL = uipanel(app.SetparametersTab_SERIAL);
            app.A_TP_SERIAL = uiaxes(app.PlotResults_SERIAL);
            title(app.A_TP_SERIAL, 'Tracked Particles')
            app.A_TP_SERIAL.XTick = [];
            app.A_TP_SERIAL.YTick = [];
            app.A_disp_SERIAL = uiaxes(app.PlotResults_SERIAL);
            title(app.A_disp_SERIAL, 'Displacement')
            app.A_disp_SERIAL.XTick = [];
            app.A_disp_SERIAL.YTick = [];
            app.TabGroup4 = uitabgroup(app.SetparametersTab_SERIAL);
            app.BeadParamsTab = uitab(app.TabGroup4);
            app.BeadParamsTab.Title = 'Bead Params';
            app.beadthresholdEditFieldLabel = uilabel(app.BeadParamsTab);
            app.beadthresholdEditFieldLabel.Text = 'bead threshold';
            app.beadthresholdEditField_SERIAL = uieditfield(app.BeadParamsTab, 'numeric');
            app.beadthresholdEditField_SERIAL.Limits = [0 1];
            app.beadthresholdEditField_SERIAL.Value = 0.5;
            app.avgbeadsizeEditFieldLabel = uilabel(app.BeadParamsTab);
            app.avgbeadsizeEditFieldLabel.Text = 'avg bead size';
            app.avgbeadsizeEditField_SERIAL = uieditfield(app.BeadParamsTab, 'numeric');
            app.avgbeadsizeEditField_SERIAL.Limits = [1 Inf];
            app.avgbeadsizeEditField_SERIAL.Value = 3;
            app.minbeadsizeEditFieldLabel = uilabel(app.BeadParamsTab);
            app.minbeadsizeEditFieldLabel.Text = 'min bead size';
            app.minbeadsizeEditField_SERIAL = uieditfield(app.BeadParamsTab, 'numeric');
            app.minbeadsizeEditField_SERIAL.Limits = [1 Inf];
            app.minbeadsizeEditField_SERIAL.Value = 2;
            app.maxbeadsizeEditFieldLabel = uilabel(app.BeadParamsTab);
            app.maxbeadsizeEditFieldLabel.Text = 'max bead size';
            app.maxbeadsizeEditField_SERIAL = uieditfield(app.BeadParamsTab, 'numeric');
            app.maxbeadsizeEditField_SERIAL.Limits = [1 Inf];
            app.maxbeadsizeEditField_SERIAL.Value = 20;
            app.maxwinsizeLabel = uilabel(app.BeadParamsTab);
            app.maxwinsizeLabel.Text = 'max win size';
            app.maxwinsizeEditField_1_SERIAL = uieditfield(app.BeadParamsTab, 'numeric');
            app.maxwinsizeEditField_1_SERIAL.Limits = [1 Inf];
            app.maxwinsizeEditField_1_SERIAL.Value = 5;
            app.distMissingEditFieldLabel = uilabel(app.BeadParamsTab);
            app.distMissingEditFieldLabel.Text = 'dist Missing';
            app.distMissingEditField_SERIAL = uieditfield(app.BeadParamsTab, 'numeric');
            app.distMissingEditField_SERIAL.Limits = [1 Inf];
            app.distMissingEditField_SERIAL.Value = 2;
            app.BeadColorDropDownLabel = uilabel(app.BeadParamsTab);
            app.BeadColorDropDownLabel.Text = 'Bead Color';
            app.BeadColorDropDown_SERIAL = uidropdown(app.BeadParamsTab);
            app.BeadColorDropDown_SERIAL.Items = {'black', 'white', ''};
            app.BeadColorDropDown_SERIAL.Value = 'white';
            app.PSFtypeDropDownLabel = uilabel(app.BeadParamsTab);
            app.PSFtypeDropDownLabel.Text = 'PSF type';
            app.PSFtypeDropDown_SERIAL = uidropdown(app.BeadParamsTab);
            app.PSFtypeDropDown_SERIAL.Items = {'None', 'fspecial'};
            app.PSFtypeDropDown_SERIAL.Value = 'None';
            app.TrackOptsTab = uitab(app.TabGroup4);
            app.TrackOptsTab.Title = 'Track Opts';
            app.DetectionmethodDropDownLabel = uilabel(app.TrackOptsTab);
            app.DetectionmethodDropDownLabel.Text = 'Detection method';
            app.DetectionmethodDropDown_SERIAL = uidropdown(app.TrackOptsTab);
            app.DetectionmethodDropDown_SERIAL.Items = {'LoG', 'TPT', 'sift', ''};
            app.DetectionmethodDropDown_SERIAL.Value = 'LoG';
            app.SearchFieldEditFieldLabel = uilabel(app.TrackOptsTab);
            app.SearchFieldEditFieldLabel.Text = 'Search Field';
            app.SearchFieldEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.SearchFieldEditField_SERIAL.Limits = [1 Inf];
            app.SearchFieldEditField_SERIAL.Value = 60;
            app.MaxNeighborsEditFieldLabel = uilabel(app.TrackOptsTab);
            app.MaxNeighborsEditFieldLabel.Text = 'Max Neighbors';
            app.MaxNeighborsEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.MaxNeighborsEditField_SERIAL.Limits = [1 Inf];
            app.MaxNeighborsEditField_SERIAL.Value = 25;
            app.MinNeighborsEditFieldLabel = uilabel(app.TrackOptsTab);
            app.MinNeighborsEditFieldLabel.Text = 'Min Neighbors';
            app.MinNeighborsEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.MinNeighborsEditField_SERIAL.Limits = [1 Inf];
            app.MinNeighborsEditField_SERIAL.Value = 1;
            app.GlobalstepsolverDropDownLabel = uilabel(app.TrackOptsTab);
            app.GlobalstepsolverDropDownLabel.Text = 'Global step solver';
            app.GlobalstepsolverDropDown_SERIAL = uidropdown(app.TrackOptsTab);
            app.GlobalstepsolverDropDown_SERIAL.Items = {'Moving lease square ', 'Global regularization', 'ADMM iterations'};
            app.GlobalstepsolverDropDown_SERIAL.Value = 'ADMM iterations';
            app.regularizationcoefEditFieldLabel = uilabel(app.TrackOptsTab);
            app.regularizationcoefEditFieldLabel.Text = 'regularization coef.';
            app.regularizationcoefEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.regularizationcoefEditField_SERIAL.Limits = [1e-09 1];
            app.regularizationcoefEditField_SERIAL.Value = 0.01;
            app.outlierthreshEditFieldLabel = uilabel(app.TrackOptsTab);
            app.outlierthreshEditFieldLabel.Text = 'outlier thresh';
            app.outlierthreshEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.outlierthreshEditField_SERIAL.Limits = [1 Inf];
            app.outlierthreshEditField_SERIAL.Value = 2;
            app.MaxADMMiterEditFieldLabel = uilabel(app.TrackOptsTab);
            app.MaxADMMiterEditFieldLabel.Text = 'Max ADMM iter';
            app.MaxADMMiterEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.MaxADMMiterEditField_SERIAL.Limits = [1 Inf];
            app.MaxADMMiterEditField_SERIAL.Value = 20;
            app.ADMMthreshEditFieldLabel = uilabel(app.TrackOptsTab);
            app.ADMMthreshEditFieldLabel.Text = 'ADMM thresh';
            app.ADMMthreshEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.ADMMthreshEditField_SERIAL.Limits = [1e-09 1];
            app.ADMMthreshEditField_SERIAL.Value = 0.01;
            app.MaxstrainNeighborsLabel = uilabel(app.TrackOptsTab);
            app.MaxstrainNeighborsLabel.Text = 'Max strain Neighbors';
            app.MaxstrainNeighborsEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.MaxstrainNeighborsEditField_SERIAL.Limits = [1 Inf];
            app.MaxstrainNeighborsEditField_SERIAL.Value = 20;
            app.xstepEditFieldLabel = uilabel(app.TrackOptsTab);
            app.xstepEditFieldLabel.Text = 'x step';
            app.xstepEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.xstepEditField_SERIAL.Limits = [1 Inf];
            app.xstepEditField_SERIAL.Value = 1;
            app.tstepEditFieldLabel = uilabel(app.TrackOptsTab);
            app.tstepEditFieldLabel.Text = 't step';
            app.tstepEditField_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.tstepEditField_SERIAL.Limits = [1 Inf];
            app.tstepEditField_SERIAL.Value = 1;
            app.localsolvermethodDropDownLabel = uilabel(app.TrackOptsTab);
            app.localsolvermethodDropDownLabel.Text = 'local solver method';
            app.localsolvermethodDropDown_SERIAL = uidropdown(app.TrackOptsTab);
            app.localsolvermethodDropDown_SERIAL.Items = {'topology based', 'histogram based'};
            app.localsolvermethodDropDown_SERIAL.Value = 'topology based';
            app.ParticleTypeDropDownLabel = uilabel(app.TrackOptsTab);
            app.ParticleTypeDropDownLabel.Text = 'Particle Type';
            app.ParticleTypeDropDown_SERIAL = uidropdown(app.TrackOptsTab);
            app.ParticleTypeDropDown_SERIAL.Items = {'hard', 'soft'};
            app.ParticleTypeDropDown_SERIAL.Value = 'hard';
            app.TrackingModeDropDownLabel = uilabel(app.TrackOptsTab);
            app.TrackingModeDropDownLabel.Text = 'Tracking Mode';
            app.TrackingModeDropDown_SERIAL = uidropdown(app.TrackOptsTab);
            app.TrackingModeDropDown_SERIAL.Items = {'cum', 'inc'};
            app.TrackingModeDropDown_SERIAL.Value = 'cum';
            app.UsepreviousresultstodetectnewparticlesCheckBox_SERIAL = uicheckbox(app.TrackOptsTab);
            app.UsepreviousresultstodetectnewparticlesCheckBox_SERIAL.Text = 'Use previous results to detect new particles?';
            app.UsepreviousresultstodetectnewparticlesCheckBox_SERIAL.Value = true;
            app.VirtualstraingausesizeLabel = uilabel(app.TrackOptsTab);
            app.VirtualstraingausesizeLabel.Text = 'Virtual strain gause size';
            app.Maxstrain_fos_SERIAL = uieditfield(app.TrackOptsTab, 'numeric');
            app.Maxstrain_fos_SERIAL.Limits = [1 Inf];
            app.Maxstrain_fos_SERIAL.Value = 60;
            app.DisplacementsTab = uitab(app.TabGroup);
            app.DisplacementsTab.Title = 'Displacements';
            app.AnalyzedImagesPanel_2 = uipanel(app.DisplacementsTab);
            app.AnalyzedImagesPanel_2.Title = 'Analyzed Images';
            app.DisplacementTree = uitree(app.AnalyzedImagesPanel_2);
            app.DisplacementTree.Multiselect = 'on';
            app.Node_2 = uitreenode(app.DisplacementTree);
            app.Node_2.Text = 'Node';
            app.Node2_2 = uitreenode(app.Node_2);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.Node_2);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.Node_2);
            app.Node4_2.Text = 'Node4';
            app.StrainsTab = uitab(app.TabGroup);
            app.StrainsTab.Title = 'Strains';
            app.StrainMap = uiaxes(app.StrainsTab);
            app.StrainMap.XTick = [];
            app.StrainMap.YTick = [];
            app.AnalyzedImagesPanel = uipanel(app.StrainsTab);
            app.AnalyzedImagesPanel.Title = 'Analyzed Images';
            app.StrainTree = uitree(app.AnalyzedImagesPanel);
            app.StrainTree.Multiselect = 'on';
            app.objName1Node = uitreenode(app.StrainTree);
            app.objName1Node.Text = 'objName1';
            app.Node_6 = uitreenode(app.objName1Node);
            app.Node_6.Text = '1';
            app.Node_7 = uitreenode(app.objName1Node);
            app.Node_7.Text = '2';
            app.Node_8 = uitreenode(app.objName1Node);
            app.Node_8.Text = '3';
            app.objName2Node = uitreenode(app.StrainTree);
            app.objName2Node.Text = 'objName2';
            app.Node_4 = uitreenode(app.objName2Node);
            app.Node_4.Text = '1';
            app.Node_5 = uitreenode(app.objName2Node);
            app.Node_5.Text = '2';
            app.Node_3 = uitreenode(app.objName2Node);
            app.Node_3.Text = '3';
            app.OptionsPanel = uipanel(app.StrainsTab);
            app.OptionsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.MethodsButton = uitogglebutton(app.OptionsButtonGroup);
            app.MethodsButton.Text = 'Methods';
            app.MethodsButton.Value = true;
            app.OverlayButton = uitogglebutton(app.OptionsButtonGroup);
            app.OverlayButton.Enable = 'off';
            app.OverlayButton.Text = 'Overlay';
            app.ScalebarButton = uitogglebutton(app.OptionsButtonGroup);
            app.ScalebarButton.Enable = 'off';
            app.ScalebarButton.Text = 'Scalebar';
            app.ColorbarButton = uitogglebutton(app.OptionsButtonGroup);
            app.ColorbarButton.Enable = 'off';
            app.ColorbarButton.Text = 'Colorbar';
            app.ExportButton_2 = uitogglebutton(app.OptionsButtonGroup);
            app.ExportButton_2.Text = 'Export';
            app.OverlayoptionsPanel = uipanel(app.OptionsPanel);
            app.OverlayoptionsPanel.Title = ' Overlay options';
            app.OverlayoptionsPanel.Visible = 'off';
            app.alphaEditFieldLabel = uilabel(app.OverlayoptionsPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.OverlayoptionsPanel, 'text');
            app.OverlayStrainMapButtonGroup = uibuttongroup(app.OverlayoptionsPanel);
            app.OverlayStrainMapButtonGroup.Title = 'Overlay Strain Map';
            app.OverlayontoreferenceimageButton = uiradiobutton(app.OverlayStrainMapButtonGroup);
            app.OverlayontoreferenceimageButton.Text = 'Overlay onto reference image';
            app.OverlaydeformedimagesButton = uiradiobutton(app.OverlayStrainMapButtonGroup);
            app.OverlaydeformedimagesButton.Text = 'Overlay deformed images';
            app.OverlaydeformedimagesButton.Value = true;
            app.DonotoverlaydataButton = uiradiobutton(app.OverlayStrainMapButtonGroup);
            app.DonotoverlaydataButton.Text = 'Do not overlay data';
            app.ScalebaroptionsPanel = uipanel(app.OptionsPanel);
            app.ScalebaroptionsPanel.Title = 'Scalebar options';
            app.ScalebaroptionsPanel.Visible = 'off';
            app.IncludescalebarCheckBox = uicheckbox(app.ScalebaroptionsPanel);
            app.IncludescalebarCheckBox.Text = 'Include scalebar';
            app.IncludescalebarCheckBox.Value = true;
            app.xlocationEditFieldLabel = uilabel(app.ScalebaroptionsPanel);
            app.xlocationEditFieldLabel.Text = 'x-location';
            app.xlocationEditField = uieditfield(app.ScalebaroptionsPanel, 'numeric');
            app.ylocationEditFieldLabel = uilabel(app.ScalebaroptionsPanel);
            app.ylocationEditFieldLabel.Text = 'y-location';
            app.ylocationEditField = uieditfield(app.ScalebaroptionsPanel, 'numeric');
            app.lengthpxEditFieldLabel = uilabel(app.ScalebaroptionsPanel);
            app.lengthpxEditFieldLabel.Text = 'length (px)';
            app.lengthpxEditField = uieditfield(app.ScalebaroptionsPanel, 'numeric');
            app.thicknesspxEditFieldLabel = uilabel(app.ScalebaroptionsPanel);
            app.thicknesspxEditFieldLabel.Text = 'thickness (px)';
            app.thicknesspxEditField = uieditfield(app.ScalebaroptionsPanel, 'numeric');
            app.ColorDropDownLabel = uilabel(app.ScalebaroptionsPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.ScalebaroptionsPanel);
            app.ColorDropDown.Items = {'white', 'black', 'red', 'blue', 'green'};
            app.ColorDropDown.Value = 'white';
            app.fontsizeEditFieldLabel = uilabel(app.ScalebaroptionsPanel);
            app.fontsizeEditFieldLabel.Text = 'font size';
            app.fontsizeEditField = uieditfield(app.ScalebaroptionsPanel, 'numeric');
            app.IncludetextCheckBox = uicheckbox(app.ScalebaroptionsPanel);
            app.IncludetextCheckBox.Text = 'Include text';
            app.IncludetextCheckBox.Value = true;
            app.ExportimagesPanel = uipanel(app.OptionsPanel);
            app.ExportimagesPanel.Title = 'Export images';
            app.ExportimagesPanel.Visible = 'off';
            app.StartButton = uibutton(app.ExportimagesPanel, 'push');
            app.StartButton.Text = 'Start';
            app.ExporttypeDropDownLabel = uilabel(app.ExportimagesPanel);
            app.ExporttypeDropDownLabel.Text = 'Export type';
            app.ExporttypeDropDown = uidropdown(app.ExportimagesPanel);
            app.ExporttypeDropDown.Items = {'jpg', 'tiff', 'avi', ''};
            app.ExporttypeDropDown.Value = 'jpg';
            app.ColorbaroptionsPanel = uipanel(app.OptionsPanel);
            app.ColorbaroptionsPanel.Title = 'Colorbar options';
            app.ColorbaroptionsPanel.Visible = 'off';
            app.IncludecolorbarCheckBox = uicheckbox(app.ColorbaroptionsPanel);
            app.IncludecolorbarCheckBox.Text = 'Include colorbar';
            app.IncludecolorbarCheckBox.Value = true;
            app.strainrangeEditFieldLabel = uilabel(app.ColorbaroptionsPanel);
            app.strainrangeEditFieldLabel.Text = 'strain range';
            app.strainrangeEditField = uieditfield(app.ColorbaroptionsPanel, 'text');
            app.strainrangeEditField.Editable = 'off';
            app.strainrangeEditField.Value = '1';
            app.strainmeanEditFieldLabel = uilabel(app.ColorbaroptionsPanel);
            app.strainmeanEditFieldLabel.Text = 'strain mean';
            app.strainmeanEditField = uieditfield(app.ColorbaroptionsPanel, 'text');
            app.strainmeanEditField.Editable = 'off';
            app.strainmeanEditField.Value = '-1';
            app.SegmentcountEditFieldLabel = uilabel(app.ColorbaroptionsPanel);
            app.SegmentcountEditFieldLabel.Text = 'Segment count';
            app.SegmentcountEditField = uieditfield(app.ColorbaroptionsPanel, 'numeric');
            app.lowerrangeEditFieldLabel = uilabel(app.ColorbaroptionsPanel);
            app.lowerrangeEditFieldLabel.Text = 'lower range';
            app.lowerrangeEditField = uieditfield(app.ColorbaroptionsPanel, 'numeric');
            app.upperrangeEditFieldLabel = uilabel(app.ColorbaroptionsPanel);
            app.upperrangeEditFieldLabel.Text = 'upper range';
            app.upperrangeEditField = uieditfield(app.ColorbaroptionsPanel, 'numeric');
            app.StraincomputationoptionsPanel = uipanel(app.OptionsPanel);
            app.StraincomputationoptionsPanel.Title = 'Strain computation options';
            app.StraincomputationmethodDropDownLabel = uilabel(app.StraincomputationoptionsPanel);
            app.StraincomputationmethodDropDownLabel.Text = 'Strain computation method';
            app.StraincomputationmethodDropDown = uidropdown(app.StraincomputationoptionsPanel);
            app.StraincomputationmethodDropDown.Items = {'Finite Difference', 'Finite Element', 'Plane Fitting', 'Direct output', ''};
            app.StraincomputationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @StraincomputationmethodDropDownValueChanged, true);
            app.StraincomputationmethodDropDown.Value = 'Finite Difference';
            app.DisplacementsmoothingEditFieldLabel = uilabel(app.StraincomputationoptionsPanel);
            app.DisplacementsmoothingEditFieldLabel.Enable = 'off';
            app.DisplacementsmoothingEditFieldLabel.Text = 'Displacement smoothing';
            app.DisplacementsmoothingEditField = uieditfield(app.StraincomputationoptionsPanel, 'numeric');
            app.DisplacementsmoothingEditField.Enable = 'off';
            app.WindowSizeEditFieldLabel = uilabel(app.StraincomputationoptionsPanel);
            app.WindowSizeEditFieldLabel.Visible = 'off';
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField_StrainPlane = uieditfield(app.StraincomputationoptionsPanel, 'numeric');
            app.WindowSizeEditField_StrainPlane.Limits = [2 Inf];
            app.WindowSizeEditField_StrainPlane.Visible = 'off';
            app.WindowSizeEditField_StrainPlane.Value = 2;
            app.StrainTypePannel = uipanel(app.StraincomputationoptionsPanel);
            app.StraintypeDropDownLabel = uilabel(app.StrainTypePannel);
            app.StraintypeDropDownLabel.Text = 'Strain type';
            app.StraintypeDropDown = uidropdown(app.StrainTypePannel);
            app.StraintypeDropDown.Items = {'Infinitesimal', 'Eulerian', 'Green-Lagrangian'};
            app.StraintypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StraintypeDropDownValueChanged, true);
            app.StraintypeDropDown.Value = 'Infinitesimal';
            app.DisplayStrain = uipanel(app.StraincomputationoptionsPanel);
            app.PlotStrainDropDownLabel = uilabel(app.DisplayStrain);
            app.PlotStrainDropDownLabel.Text = 'Plot Strain ';
            app.PlotStrainDropDown = uidropdown(app.DisplayStrain);
            app.PlotStrainDropDown.Items = {'exx', 'exy', 'eyy', 'max shear', 'max principal', 'min principal', 'vonMises'};
            app.PlotStrainDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotStrainDropDownValueChanged, true);
            app.PlotStrainDropDown.Value = 'vonMises';
            app.StartAnalysis_Strain = uibutton(app.StrainsTab, 'push');
            app.StartAnalysis_Strain.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysis_StrainButtonPushed, true);
            app.StartAnalysis_Strain.Text = 'Start Analysis';
            app.NextImageButton = uibutton(app.StrainsTab, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.PreviousImageButton = uibutton(app.StrainsTab, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousImageButtonPushed, true);
            app.PreviousImageButton.Text = 'Previous Image';
            app.StrainCurrentPlotLabel = uilabel(app.StrainsTab);
            app.StrainCurrentPlotLabel.Text = '  ';
            app.ExportImportDataTab = uitab(app.TabGroup);
            app.ExportImportDataTab.Title = 'Export / Import Data';
            app.SelectAnalysisListBoxLabel_5 = uilabel(app.ExportImportDataTab);
            app.SelectAnalysisListBoxLabel_5.Text = 'Select Analysis';
            app.SelectAnalysisListBox_Export = uilistbox(app.ExportImportDataTab);
            app.SelectAnalysisListBox_Export.Items = {};
            app.SelectAnalysisListBox_Export.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBox_ExportValueChanged, true);
            app.SelectAnalysisListBox_Export.Value = {};
            app.ExportButton = uibutton(app.ExportImportDataTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.EditFieldLabel = uilabel(app.ExportImportDataTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.ExportImportDataTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField.Value = 'MyFileName.mat';
            app.LoadDataButton = uibutton(app.ExportImportDataTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TypeLabel = uilabel(app.UIFigure);
            app.TypeLabel.Text = 'corALate ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorALate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end